classdef PreProcB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PreprocessingTab                matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        PreprocessingEDFFilePanel       matlab.ui.container.Panel
        SelectBadChannelsButton         matlab.ui.control.Button
        PreprocessButton                matlab.ui.control.Button
        ValueHzEditField_2              matlab.ui.control.NumericEditField
        ValueHzEditField_2Label         matlab.ui.control.Label
        NotchfilterCheckBox             matlab.ui.control.CheckBox
        ValueHzEditField                matlab.ui.control.NumericEditField
        ValueHzEditFieldLabel           matlab.ui.control.Label
        HighpassFilterCheckBox          matlab.ui.control.CheckBox
        FilesPanel                      matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        ProtocolfileButton              matlab.ui.control.Button
        TriggerTypeonSEEGDropDown       matlab.ui.control.DropDown
        TriggerTypeonSEEGDropDownLabel  matlab.ui.control.Label
        NumberofblockinedffilesEditField  matlab.ui.control.EditField
        NumberofblockinedffilesEditFieldLabel  matlab.ui.control.Label
        AlignMarkersButton              matlab.ui.control.Button
        EMUIDEditField                  matlab.ui.control.EditField
        EMUIDEditFieldLabel             matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ProtocolDropDown                matlab.ui.control.DropDown
        ProtocolDropDownLabel           matlab.ui.control.Label
        SessionBehaviordataButton       matlab.ui.control.Button
        EDFfilesButton                  matlab.ui.control.Button
        ProcesssingTab                  matlab.ui.container.Tab
        SavematfilesButton              matlab.ui.control.Button
        FilesManagementPanel            matlab.ui.container.Panel
        BrowseInputButton               matlab.ui.control.Button
        UsethefilefromPreprocessingCheckBox  matlab.ui.control.CheckBox
        EDFfiletoanalyseLabel           matlab.ui.control.Label
        OverwriteOutputfilesCheckBox    matlab.ui.control.CheckBox
        ProjectsFolderinEMUserverLabel  matlab.ui.control.Label
        OutputFolderButton              matlab.ui.control.Button
        FilteringDownsamplingMontagePanel  matlab.ui.container.Panel
        SaveEDFfilesButton              matlab.ui.control.Button
        MontageButtonGroup              matlab.ui.container.ButtonGroup
        MonopolarButton                 matlab.ui.control.RadioButton
        AverageCommonButton             matlab.ui.control.RadioButton
        BipolarButton                   matlab.ui.control.RadioButton
        DropDown                        matlab.ui.control.DropDown
        DownsampleCheckBox              matlab.ui.control.CheckBox
        Tree                            matlab.ui.container.CheckBoxTree
        MultiBandfilterNode             matlab.ui.container.TreeNode
        Delta14HzNode                   matlab.ui.container.TreeNode
        Theta48HzNode                   matlab.ui.container.TreeNode
        Alpha812HzNode                  matlab.ui.container.TreeNode
        Beta1230HzNode                  matlab.ui.container.TreeNode
        LowGamma3065HzNode              matlab.ui.container.TreeNode
        HighGamma6580HzNode             matlab.ui.container.TreeNode
        Ripple80250HzNode               matlab.ui.container.TreeNode
        FastRipple250500HzNode          matlab.ui.container.TreeNode
        HighFrequencyEditField          matlab.ui.control.EditField
        HighFrequencyEditFieldLabel     matlab.ui.control.Label
        LowFrequencyEditField           matlab.ui.control.EditField
        LowFrequencyEditFieldLabel      matlab.ui.control.Label
        BroadbandanalysisLabel          matlab.ui.control.Label
        TimeFrequencyAnalysisPanel      matlab.ui.container.Panel
        DropDown_2                      matlab.ui.control.DropDown
        VisualizeButton_2               matlab.ui.control.Button
        RunButton_2                     matlab.ui.control.Button
        TimeFrequencyMapCheckBox        matlab.ui.control.CheckBox
        PowerSpectralDensityCheckBox    matlab.ui.control.CheckBox
        EpochsPanel                     matlab.ui.container.Panel
        VisualizeButton                 matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        TimeaftermarkermsEditField      matlab.ui.control.NumericEditField
        TimeaftermarkermsEditFieldLabel  matlab.ui.control.Label
        TimebeforemarkermsEditField     matlab.ui.control.NumericEditField
        TimebeforemarkermsEditFieldLabel  matlab.ui.control.Label
        AverageCheckBox                 matlab.ui.control.CheckBox
        TimeLockDropDown                matlab.ui.control.DropDown
        TimeLockDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        path % Description
        SEEGfile
    end
    
    properties (Access = public)
        ppb % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIFigure, 'Visible', 'off')
            emuDir = uigetdir(pwd, 'Select EMU server path');
            if isempty(emuDir)
                warndlg('EMU server path is missing, Work will be done locally.')
                emuDir =[];
            end
            app.ppb = ppb_defaults(emuDir);
            if ~isempty(emuDir) && emuDir ~= 0
                app.path = emuDir;
                % Create the protocol drop menu list
                listing = dir(app.ppb.protocol.directory);
                listing = listing(~[listing.isdir],:);
                protocolName = [];
                for i=1:size(listing, 1)
                    if startsWith(listing(i).name, 'Experiment_Protocol')
                        listName = split(listing(i).name, '_');
                        protocolName = [protocolName, string(listName{3})];
                    end
                end
                if ~isempty(protocolName)
                    app.ProtocolDropDown.Items = protocolName;
                end
            end
            set(app.UIFigure, 'Visible', 'on')
        end

        % Button pushed function: EDFfilesButton
        function EDFfilesButtonPushed(app, event)
            [files, pathname] = uigetfile('*.edf', 'Select EDF files for this session', ...
                app.path, 'MultiSelect','on');
            app.path = pathname;
            if iscell(files)
                nfiles = length(files);
                app.SEEGfile= cell(nfiles,1);
                for n=1:nfiles
                    app.SEEGfile{n,1} = fullfile(pathname, files{n});
                end
            else
                app.SEEGfile = {fullfile(pathname, files)};
            end
            if ~isempty(app.ppb.seeg.numBlock)
                [app.ppb, TrigChan] = prepare_seeg_data(app.SEEGfile, app.ppb, 'trigger');
                app.TriggerTypeonSEEGDropDown.Items = TrigChan;
            end
            app.Label.Text = join(files, ';');
            drawnow;
            figure(app.UIFigure)
        end

        % Value changed function: NumberofblockinedffilesEditField
        function NumberofblockinedffilesEditFieldValueChanged(app, event)
            app.ppb.seeg.numBlock = app.NumberofblockinedffilesEditField.Value;
            if ~isempty(app.SEEGfile) && length(app.SEEGfile) >= 1
                [app.ppb, TrigChan] = prepare_seeg_data(app.SEEGfile, app.ppb, 'trigger');
                app.TriggerTypeonSEEGDropDown.Items = TrigChan;
                app.Label.Text = app.ppb.seeg.filename;
            end
        end

        % Drop down opening function: TriggerTypeonSEEGDropDown
        function TriggerTypeonSEEGDropDownOpening(app, event)
            if isempty(app.TriggerTypeonSEEGDropDown.Items)
                [app.ppb, TrigChan] = prepare_seeg_data(app.SEEGfile, app.ppb, 'trigger');
                app.TriggerTypeonSEEGDropDown.Items = TrigChan;
            end
        end

        % Button pushed function: SessionBehaviordataButton
        function SessionBehaviordataButtonPushed(app, event)
            [file, pathname] = uigetfile({'*.xlsx'; '*.xls'; '*.csv'}, ...
                'Select session Behavior file', ...
                app.path);
            app.ppb.behavior.filename = fullfile(pathname, file);
            app.Label_2.Text = file;
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: ProtocolfileButton
        function ProtocolfileButtonPushed(app, event)
            [file, pathname] = uigetfile({'*.xlsx'; '*.xls'; '*.csv'}, ...
                'Select Protocol file', ...
                app.path);
            app.ppb.protocol.filename = fullfile(pathname, file);
            app.Label_3.Text = file;
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: AlignMarkersButton
        function AlignMarkersButtonPushed(app, event)
            %ADD A PROGRESS BAR
            progBar = waitbar(0, 'Please wait alignement in progress ...');
            % Get EMU ID
            app.ppb.emuID = app.EMUIDEditField.Value;
            % Check if there is everything
            if isempty(app.ppb.protocol.filename)
                errordlg("Protocol file is missing")
                return
            elseif isempty(app.ppb.seeg.filename)
                errordlg("SEEG file(s) are missing")
                return
            elseif isempty(app.ppb.behavior.filename)
                errordlg("Behavior file is missing")
                return
            end
            if isempty(app.ppb.seeg.trigChan)
                app.ppb.seeg.trigChan = app.TriggerTypeonSEEGDropDown.Value;
            end
            % Make sure the trigger channel correspond to the protocol value
            [app.ppb, error] = read_protocol_file(app.ppb);
            if error
                return
            end
            [app.ppb, error] = prepare_seeg_data(app.SEEGfile, app.ppb, 'compare');
            if error
                return
            end
            waitbar(0.2, progBar, 'Please wait alignement in progress ...')
            align_seeg_triggers(app.ppb, progBar);
        end

        % Value changed function: ProtocolDropDown
        function ProtocolDropDownValueChanged(app, event)
            value = app.ProtocolDropDown.Value;
            app.ppb.protocol.filename = fullfile(app.ppb.protocol.directory, value);
        end

        % Value changed function: TriggerTypeonSEEGDropDown
        function TriggerTypeonSEEGDropDownValueChanged(app, event)
            value = app.TriggerTypeonSEEGDropDown.Value;
            app.ppb.seeg.trigChan = value;
        end

        % Value changed function: EMUIDEditField
        function EMUIDEditFieldValueChanged(app, event)
            value = app.EMUIDEditField.Value;
            app.ppb.emuID = value;

        end

        % Value changed function: HighpassFilterCheckBox
        function HighpassFilterCheckBoxValueChanged(app, event)
            value = app.HighpassFilterCheckBox.Value;
            app.ppb.preprocess.HF = value;
            app.ppb.preprocess.HFvalue = app.ValueHzEditField.Value;
        end

        % Value changed function: ValueHzEditField
        function ValueHzEditFieldValueChanged(app, event)
            value = app.ValueHzEditField.Value;
            app.ppb.preprocess.HFvalue = value;
        end

        % Value changed function: NotchfilterCheckBox
        function NotchfilterCheckBoxValueChanged(app, event)
            value = app.NotchfilterCheckBox.Value;
            app.ppb.preprocess.NF = value;
            app.ppb.preprocess.NFvalue = app.ValueHzEditField_2.Value;
        end

        % Value changed function: ValueHzEditField_2
        function ValueHzEditField_2ValueChanged(app, event)
            value = app.ValueHzEditField_2.Value;
            app.ppb.preprocess.NFvalue = value;
        end

        % Button pushed function: PreprocessButton
        function PreprocessButtonPushed(app, event)
            % initiate the data
            if isempty(app.ppb.seeg.data)
                f = waitbar(0.1, 'Reading Data, please wait ...');
                [app.ppb, error] = prepare_seeg_data(app.SEEGfile, app.ppb, 'read');
                if error
                    waitbar(0.9, f, 'Error');
                    close(f);
                    return
                end
                waitbar(1, f, 'Data are ready for preprocessing ...');
                close(f);
            end
            progBar = waitbar(0, 'Filtering in process ...');
            % Do the filtering
            suffix = {};
            seegData = app.ppb.seeg.data;
            if app.ppb.preprocess.NF
                 app.ppb.preprocess.data = ft_preproc_dftfilter(seegData, app.ppb.seeg.hdr.Fs, app.ppb.preprocess.NFvalue);
                 seegData = app.ppb.preprocess.data;
                 waitbar(0.3, progBar, 'Notch filtering is done ...');
                 suffix{end+1} = strcat('nf', num2str(app.ppb.preprocess.NFvalue));
            end
            if app.ppb.preprocess.HF 
                app.ppb.preprocess.data =  ft_preproc_highpassfilter(seegData, ...
                    app.ppb.seeg.hdr.Fs,  app.ppb.preprocess.HFvalue, [], 'firws');
                waitbar(0.6, progBar, 'High Pass filtering is done ...');
                suffix{end+1} = strcat('hf', replace(num2str(app.ppb.preprocess.HFvalue), '.', ''));
            end
            % Save Bad Channels as txt file
            [~, filename, ~] = fileparts(app.ppb.seeg.filename);
            app.ppb.preprocess.badChannelsFilename = fullfile(app.ppb.emuDirPreprocessing, strcat(filename, '_badChannels.txt'));
            if ~isempty(app.ppb.preprocess.badChannels)
                fid = fopen(app.ppb.preprocess.badChannelsFilename, 'w');
                for i = 1:length(app.ppb.preprocess.badChannels)
                    fprintf(fid, '%s\n', app.ppb.preprocess.badChannels{i});
                end
                fclose(fid);
            end

            % New name of the edf file
            suffix = join(suffix, '_');
            app.ppb.preprocess.filename = fullfile(app.ppb.emuDirPreprocessing, ...
                strcat(filename, '_', suffix{1}, '.edf'));

            % Save as edf
            answer = questdlg('Do you want to save edf file of preprocessing without Bad Channels?', ...
                'Saving files', 'Yes', 'No', 'Cancel');
            switch answer
                case 'Yes'
                    save_as_edf(app.ppb.seeg.hdr, app.ppb.preprocess.data, ...
                        app.ppb.preprocess.filename, app.ppb.preprocess.badChannels);
                case 'No'
                    save_as_edf(app.ppb.seeg.hdr, app.ppb.preprocess.data, ...
                        app.ppb.preprocess.filename);
                case {'', ' '}
                    msgbox('EDF File has not been saved.')
                    return
            end
            

        end

        % Button pushed function: SelectBadChannelsButton
        function SelectBadChannelsButtonPushed(app, event)
            if isempty(app.ppb.seeg.data)
                f = waitbar(0.1, 'Reading Data, please wait ...');
                [app.ppb, error] = prepare_seeg_data(app.SEEGfile, app.ppb, 'read');
                if error
                    waitbar(0.9, f, 'Error');
                    close(f);
                    return
                end
                waitbar(1, f, 'Data are ready for visualization ...');
                close(f);
            end
            myapp = selectBadChannels(app.ppb);
            while myapp.done == 0
                pause(0.05)
            end
            app.ppb.preprocess.badChannels  = myapp.ppb.preprocess.badChannels;
            myapp.closeTheWindow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.PreprocessingTab.Tag = 'HPvalue';
            app.FilesPanel = uipanel(app.PreprocessingTab);
            app.FilesPanel.Title = 'Files';
            app.EDFfilesButton = uibutton(app.FilesPanel, 'push');
            app.EDFfilesButton.ButtonPushedFcn = createCallbackFcn(app, @EDFfilesButtonPushed, true);
            app.EDFfilesButton.Text = 'EDF files';
            app.SessionBehaviordataButton = uibutton(app.FilesPanel, 'push');
            app.SessionBehaviordataButton.ButtonPushedFcn = createCallbackFcn(app, @SessionBehaviordataButtonPushed, true);
            app.SessionBehaviordataButton.Tag = 'behav';
            app.SessionBehaviordataButton.Text = 'Session Behavior data';
            app.ProtocolDropDownLabel = uilabel(app.FilesPanel);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.ProtocolDropDown = uidropdown(app.FilesPanel);
            app.ProtocolDropDown.Items = {};
            app.ProtocolDropDown.ValueChangedFcn = createCallbackFcn(app, @ProtocolDropDownValueChanged, true);
            app.ProtocolDropDown.Value = {};
            app.Label = uilabel(app.FilesPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.FilesPanel);
            app.Label_2.Text = '';
            app.EMUIDEditFieldLabel = uilabel(app.FilesPanel);
            app.EMUIDEditFieldLabel.Text = 'EMU ID';
            app.EMUIDEditField = uieditfield(app.FilesPanel, 'text');
            app.EMUIDEditField.CharacterLimits = [0 3];
            app.EMUIDEditField.InputType = 'digits';
            app.EMUIDEditField.ValueChangedFcn = createCallbackFcn(app, @EMUIDEditFieldValueChanged, true);
            app.AlignMarkersButton = uibutton(app.FilesPanel, 'push');
            app.AlignMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @AlignMarkersButtonPushed, true);
            app.AlignMarkersButton.Text = 'Align Markers';
            app.NumberofblockinedffilesEditFieldLabel = uilabel(app.FilesPanel);
            app.NumberofblockinedffilesEditFieldLabel.Text = 'Number of block in edf files';
            app.NumberofblockinedffilesEditField = uieditfield(app.FilesPanel, 'text');
            app.NumberofblockinedffilesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofblockinedffilesEditFieldValueChanged, true);
            app.TriggerTypeonSEEGDropDownLabel = uilabel(app.FilesPanel);
            app.TriggerTypeonSEEGDropDownLabel.Text = 'Trigger Type on SEEG ';
            app.TriggerTypeonSEEGDropDown = uidropdown(app.FilesPanel);
            app.TriggerTypeonSEEGDropDown.Items = {};
            app.TriggerTypeonSEEGDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TriggerTypeonSEEGDropDownOpening, true);
            app.TriggerTypeonSEEGDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerTypeonSEEGDropDownValueChanged, true);
            app.TriggerTypeonSEEGDropDown.Value = {};
            app.ProtocolfileButton = uibutton(app.FilesPanel, 'push');
            app.ProtocolfileButton.ButtonPushedFcn = createCallbackFcn(app, @ProtocolfileButtonPushed, true);
            app.ProtocolfileButton.Text = 'Protocol file';
            app.Label_3 = uilabel(app.FilesPanel);
            app.Label_3.Text = '';
            app.PreprocessingEDFFilePanel = uipanel(app.PreprocessingTab);
            app.PreprocessingEDFFilePanel.Title = 'Preprocessing EDF File';
            app.HighpassFilterCheckBox = uicheckbox(app.PreprocessingEDFFilePanel);
            app.HighpassFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighpassFilterCheckBoxValueChanged, true);
            app.HighpassFilterCheckBox.Text = 'High-pass Filter';
            app.ValueHzEditFieldLabel = uilabel(app.PreprocessingEDFFilePanel);
            app.ValueHzEditFieldLabel.Text = 'Value (Hz)';
            app.ValueHzEditField = uieditfield(app.PreprocessingEDFFilePanel, 'numeric');
            app.ValueHzEditField.ValueChangedFcn = createCallbackFcn(app, @ValueHzEditFieldValueChanged, true);
            app.ValueHzEditField.Value = 0.5;
            app.NotchfilterCheckBox = uicheckbox(app.PreprocessingEDFFilePanel);
            app.NotchfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchfilterCheckBoxValueChanged, true);
            app.NotchfilterCheckBox.Text = 'Notch filter';
            app.ValueHzEditField_2Label = uilabel(app.PreprocessingEDFFilePanel);
            app.ValueHzEditField_2Label.Text = 'Value (Hz)';
            app.ValueHzEditField_2 = uieditfield(app.PreprocessingEDFFilePanel, 'numeric');
            app.ValueHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @ValueHzEditField_2ValueChanged, true);
            app.ValueHzEditField_2.Tag = 'NFvalue';
            app.ValueHzEditField_2.Value = 60;
            app.PreprocessButton = uibutton(app.PreprocessingEDFFilePanel, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.SelectBadChannelsButton = uibutton(app.PreprocessingEDFFilePanel, 'push');
            app.SelectBadChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBadChannelsButtonPushed, true);
            app.SelectBadChannelsButton.Text = 'Select Bad Channels';
            app.Image = uiimage(app.PreprocessingTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PreProcB_logo_fb.png');
            app.ProcesssingTab = uitab(app.TabGroup);
            app.ProcesssingTab.Title = 'Processsing';
            app.EpochsPanel = uipanel(app.ProcesssingTab);
            app.EpochsPanel.Title = 'Epochs';
            app.TimeLockDropDownLabel = uilabel(app.EpochsPanel);
            app.TimeLockDropDownLabel.Text = 'Time Lock';
            app.TimeLockDropDown = uidropdown(app.EpochsPanel);
            app.AverageCheckBox = uicheckbox(app.EpochsPanel);
            app.AverageCheckBox.Text = 'Average';
            app.TimebeforemarkermsEditFieldLabel = uilabel(app.EpochsPanel);
            app.TimebeforemarkermsEditFieldLabel.Text = 'Time before marker (ms)';
            app.TimebeforemarkermsEditField = uieditfield(app.EpochsPanel, 'numeric');
            app.TimeaftermarkermsEditFieldLabel = uilabel(app.EpochsPanel);
            app.TimeaftermarkermsEditFieldLabel.Text = 'Time after marker (ms)';
            app.TimeaftermarkermsEditField = uieditfield(app.EpochsPanel, 'numeric');
            app.RunButton = uibutton(app.EpochsPanel, 'push');
            app.RunButton.Text = 'Run';
            app.VisualizeButton = uibutton(app.EpochsPanel, 'push');
            app.VisualizeButton.Text = 'Visualize';
            app.TimeFrequencyAnalysisPanel = uipanel(app.ProcesssingTab);
            app.TimeFrequencyAnalysisPanel.Title = 'Time - Frequency Analysis';
            app.PowerSpectralDensityCheckBox = uicheckbox(app.TimeFrequencyAnalysisPanel);
            app.PowerSpectralDensityCheckBox.Text = 'Power Spectral Density';
            app.TimeFrequencyMapCheckBox = uicheckbox(app.TimeFrequencyAnalysisPanel);
            app.TimeFrequencyMapCheckBox.Text = 'Time Frequency Map';
            app.RunButton_2 = uibutton(app.TimeFrequencyAnalysisPanel, 'push');
            app.RunButton_2.Text = 'Run';
            app.VisualizeButton_2 = uibutton(app.TimeFrequencyAnalysisPanel, 'push');
            app.VisualizeButton_2.Text = 'Visualize';
            app.DropDown_2 = uidropdown(app.TimeFrequencyAnalysisPanel);
            app.DropDown_2.Items = {'FFT', 'Wavelet'};
            app.DropDown_2.Value = 'FFT';
            app.FilteringDownsamplingMontagePanel = uipanel(app.ProcesssingTab);
            app.FilteringDownsamplingMontagePanel.Title = 'Filtering & Downsampling & Montage';
            app.BroadbandanalysisLabel = uilabel(app.FilteringDownsamplingMontagePanel);
            app.BroadbandanalysisLabel.Text = 'Broadband analysis';
            app.LowFrequencyEditFieldLabel = uilabel(app.FilteringDownsamplingMontagePanel);
            app.LowFrequencyEditFieldLabel.Text = 'Low Frequency';
            app.LowFrequencyEditField = uieditfield(app.FilteringDownsamplingMontagePanel, 'text');
            app.HighFrequencyEditFieldLabel = uilabel(app.FilteringDownsamplingMontagePanel);
            app.HighFrequencyEditFieldLabel.Text = 'High Frequency';
            app.HighFrequencyEditField = uieditfield(app.FilteringDownsamplingMontagePanel, 'text');
            app.Tree = uitree(app.FilteringDownsamplingMontagePanel, 'checkbox');
            app.MultiBandfilterNode = uitreenode(app.Tree);
            app.MultiBandfilterNode.Text = 'Multi-Band filter';
            app.Delta14HzNode = uitreenode(app.MultiBandfilterNode);
            app.Delta14HzNode.Text = 'Delta 1-4 Hz';
            app.Theta48HzNode = uitreenode(app.MultiBandfilterNode);
            app.Theta48HzNode.Text = 'Theta 4-8Hz';
            app.Alpha812HzNode = uitreenode(app.MultiBandfilterNode);
            app.Alpha812HzNode.Text = 'Alpha 8-12Hz';
            app.Beta1230HzNode = uitreenode(app.MultiBandfilterNode);
            app.Beta1230HzNode.Text = 'Beta 12-30Hz';
            app.LowGamma3065HzNode = uitreenode(app.MultiBandfilterNode);
            app.LowGamma3065HzNode.Text = 'Low Gamma 30-65Hz';
            app.HighGamma6580HzNode = uitreenode(app.MultiBandfilterNode);
            app.HighGamma6580HzNode.Text = 'High Gamma 65-80Hz';
            app.Ripple80250HzNode = uitreenode(app.MultiBandfilterNode);
            app.Ripple80250HzNode.Text = 'Ripple 80-250Hz';
            app.FastRipple250500HzNode = uitreenode(app.MultiBandfilterNode);
            app.FastRipple250500HzNode.Text = 'Fast Ripple 250-500Hz';
            app.DownsampleCheckBox = uicheckbox(app.FilteringDownsamplingMontagePanel);
            app.DownsampleCheckBox.Text = 'Downsample';
            app.DropDown = uidropdown(app.FilteringDownsamplingMontagePanel);
            app.DropDown.Items = {'x2', 'x4', 'x8'};
            app.DropDown.Value = 'x2';
            app.MontageButtonGroup = uibuttongroup(app.FilteringDownsamplingMontagePanel);
            app.MontageButtonGroup.Title = 'Montage';
            app.BipolarButton = uiradiobutton(app.MontageButtonGroup);
            app.BipolarButton.Text = 'Bipolar';
            app.BipolarButton.Value = true;
            app.AverageCommonButton = uiradiobutton(app.MontageButtonGroup);
            app.AverageCommonButton.Text = 'Average Common';
            app.MonopolarButton = uiradiobutton(app.MontageButtonGroup);
            app.MonopolarButton.Text = 'Monopolar';
            app.SaveEDFfilesButton = uibutton(app.FilteringDownsamplingMontagePanel, 'push');
            app.SaveEDFfilesButton.Text = 'Save EDF files';
            app.FilesManagementPanel = uipanel(app.ProcesssingTab);
            app.FilesManagementPanel.Title = 'Files Management';
            app.OutputFolderButton = uibutton(app.FilesManagementPanel, 'push');
            app.OutputFolderButton.Text = 'Output Folder';
            app.ProjectsFolderinEMUserverLabel = uilabel(app.FilesManagementPanel);
            app.ProjectsFolderinEMUserverLabel.Text = 'Projects Folder in EMU server';
            app.OverwriteOutputfilesCheckBox = uicheckbox(app.FilesManagementPanel);
            app.OverwriteOutputfilesCheckBox.Text = 'Overwrite Output files';
            app.EDFfiletoanalyseLabel = uilabel(app.FilesManagementPanel);
            app.EDFfiletoanalyseLabel.Text = 'EDF file to analyse:';
            app.UsethefilefromPreprocessingCheckBox = uicheckbox(app.FilesManagementPanel);
            app.UsethefilefromPreprocessingCheckBox.Text = 'Use the file from Pre-processing';
            app.BrowseInputButton = uibutton(app.FilesManagementPanel, 'push');
            app.BrowseInputButton.Text = 'Browse Input';
            app.SavematfilesButton = uibutton(app.ProcesssingTab, 'push');
            app.SavematfilesButton.Text = 'Save mat-files';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreProcB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end