classdef PreProcB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PreprocessingTab                matlab.ui.container.Tab
        version131Label                 matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PreprocessingEDFFilePanel       matlab.ui.container.Panel
        SelectBadChannelsButton         matlab.ui.control.Button
        PreprocessButton                matlab.ui.control.Button
        ValueHzEditField_2              matlab.ui.control.NumericEditField
        ValueHzEditField_2Label         matlab.ui.control.Label
        NotchfilterCheckBox             matlab.ui.control.CheckBox
        ValueHzEditField                matlab.ui.control.NumericEditField
        ValueHzEditFieldLabel           matlab.ui.control.Label
        HighpassFilterCheckBox          matlab.ui.control.CheckBox
        FilesPanel                      matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        ProtocolfileButton              matlab.ui.control.Button
        TriggerTypeonSEEGDropDown       matlab.ui.control.DropDown
        TriggerTypeonSEEGDropDownLabel  matlab.ui.control.Label
        NumberofblockinedffilesEditField  matlab.ui.control.EditField
        NumberofblockinedffilesEditFieldLabel  matlab.ui.control.Label
        AlignMarkersButton              matlab.ui.control.Button
        EMUIDEditField                  matlab.ui.control.EditField
        EMUIDEditFieldLabel             matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ProtocolDropDown                matlab.ui.control.DropDown
        ProtocolDropDownLabel           matlab.ui.control.Label
        SessionBehaviordataButton       matlab.ui.control.Button
        EDFfilesButton                  matlab.ui.control.Button
        ProcesssingTab                  matlab.ui.container.Tab
        ConnectivityPanel               matlab.ui.container.Panel
        VisualizeButtonConnectivity     matlab.ui.control.Button
        TimeLockDropDownConnectivity    matlab.ui.control.DropDown
        TimeLockDropDown_2Label_2       matlab.ui.control.Label
        DropDownConnectivity            matlab.ui.control.DropDown
        DataLabel                       matlab.ui.control.Label
        RunButtonConnectivity           matlab.ui.control.Button
        MethodsDropDown                 matlab.ui.control.DropDown
        MethodsDropDownLabel            matlab.ui.control.Label
        FilesManagementInputOutputPanel  matlab.ui.container.Panel
        BrowseMarkersButton             matlab.ui.control.Button
        EMUIDEditField_2                matlab.ui.control.EditField
        EMUIDEditField_2Label           matlab.ui.control.Label
        ProcessEDF                      matlab.ui.control.Label
        BrowseInputButton               matlab.ui.control.Button
        UsethefilefromPreprocessingCheckBox  matlab.ui.control.CheckBox
        OverwriteOutputfilesCheckBox    matlab.ui.control.CheckBox
        FilteringDownsamplingMontagePanel  matlab.ui.container.Panel
        VisualizeProcessButton          matlab.ui.control.Button
        RunButtonFDM                    matlab.ui.control.Button
        MultiBandfilterPanel            matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        HFFR                            matlab.ui.control.NumericEditField
        LFFR                            matlab.ui.control.NumericEditField
        HFRipple                        matlab.ui.control.NumericEditField
        LFRipple                        matlab.ui.control.NumericEditField
        HFGamma                         matlab.ui.control.NumericEditField
        LFGamma                         matlab.ui.control.NumericEditField
        HFBeta                          matlab.ui.control.NumericEditField
        LFBeta                          matlab.ui.control.NumericEditField
        HFAlpha                         matlab.ui.control.NumericEditField
        LFAlpha                         matlab.ui.control.NumericEditField
        HFTheta                         matlab.ui.control.NumericEditField
        LFTheta                         matlab.ui.control.NumericEditField
        HFDelta                         matlab.ui.control.NumericEditField
        LFDelta                         matlab.ui.control.NumericEditField
        FastRippleCheckBox              matlab.ui.control.CheckBox
        RippleCheckBox                  matlab.ui.control.CheckBox
        GammaCheckBox                   matlab.ui.control.CheckBox
        BetaCheckBox                    matlab.ui.control.CheckBox
        AlphaCheckBox                   matlab.ui.control.CheckBox
        ThetaCheckBox                   matlab.ui.control.CheckBox
        DeltaCheckBox                   matlab.ui.control.CheckBox
        MontageButtonGroup              matlab.ui.container.ButtonGroup
        MonopolarButton                 matlab.ui.control.RadioButton
        AverageCommonButton             matlab.ui.control.RadioButton
        BipolarButton                   matlab.ui.control.RadioButton
        DropDownDownsample              matlab.ui.control.DropDown
        DownsampleCheckBox              matlab.ui.control.CheckBox
        HighFrequencyEditField          matlab.ui.control.EditField
        HighFrequencyEditFieldLabel     matlab.ui.control.Label
        LowFrequencyEditField           matlab.ui.control.EditField
        LowFrequencyEditFieldLabel      matlab.ui.control.Label
        BroadbandanalysisLabel          matlab.ui.control.Label
        TimeFrequencyAnalysisPanel      matlab.ui.container.Panel
        NormalizationTFCheckBox         matlab.ui.control.CheckBox
        HighestFrequencyEditField       matlab.ui.control.NumericEditField
        HighestFrequencyHzEditFieldLabel  matlab.ui.control.Label
        DataDropDownTF                  matlab.ui.control.DropDown
        DataDropDown_2Label             matlab.ui.control.Label
        TimeLockDropDownTF              matlab.ui.control.DropDown
        TimeLockDropDown_2Label         matlab.ui.control.Label
        VisualizeButtonTF               matlab.ui.control.Button
        RunButtonTF                     matlab.ui.control.Button
        TimeFrequencyMapCheckBox        matlab.ui.control.CheckBox
        PowerSpectralDensityCheckBox    matlab.ui.control.CheckBox
        EpochsPanel                     matlab.ui.container.Panel
        TrialsselectionButton           matlab.ui.control.Button
        NormalizationCheckBox           matlab.ui.control.CheckBox
        DataDropDown                    matlab.ui.control.DropDown
        DataDropDownLabel               matlab.ui.control.Label
        VisualizeButton                 matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        TimeaftermarkermsEditField      matlab.ui.control.NumericEditField
        TimeaftermarkermsEditFieldLabel  matlab.ui.control.Label
        TimebeforemarkermsEditField     matlab.ui.control.NumericEditField
        TimebeforemarkermsEditFieldLabel  matlab.ui.control.Label
        AverageCheckBox                 matlab.ui.control.CheckBox
        TimeLockDropDown                matlab.ui.control.DropDown
        TimeLockDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        path % Description
        SEEGfile
    end
    
    properties (Access = public)
        ppb % Description
    end
    
    methods (Access = private)
        
        function turn_process_panel(app, type)

            set(app.FilteringDownsamplingMontagePanel, 'Enable', type);
            % Change the value of Downsampling accroding to hdr FS
            if strcmp(type, 'on')
                Fs = app.ppb.process.hdr.Fs;
                ItemsValue = [strcat("x1 (", num2str(Fs), ")")];
                val = 2;
                while Fs >= 512
                    Fs = Fs/2;
                    ItemsValue = [ItemsValue; strcat("x", num2str(val), " (", num2str(Fs), ")")];
                    val = val +2;
                end
                app.DropDownDownsample.Items = ItemsValue;
                app.DropDownDownsample.Value = ItemsValue(1);
            end
            
        end
        
        function get_multiband_filter(app, field)
            LFvalue = app.(strcat('LF', field)).Value;
            HFvalue = app.(strcat('HF', field)).Value;
            app.ppb.process.filter.(field) = [LFvalue, HFvalue];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIFigure, 'Visible', 'off')
            emuDir = uigetdir(pwd, 'Select EMU server path');
            if isempty(emuDir)
                warndlg('EMU server path is missing, Work will be done locally.')
                emuDir =[];
            end
            app.ppb = ppb_defaults(emuDir);
            if ~isempty(emuDir) && all(emuDir ~= 0)
                app.path = emuDir;
                % Create the protocol drop menu list
                listing = dir(app.ppb.protocol.directory);
                listing = listing(~[listing.isdir],:);
                protocolName = [];
                for i=1:size(listing, 1)
                    if startsWith(listing(i).name, 'Experiment_Protocol')
                        [~, nameFile, ~] = fileparts(listing(i).name);
                        listName = split(nameFile, '_');
                        protocolName = [protocolName, string(join(listName(3:end), '_'))];
                    end
                end
                if ~isempty(protocolName)
                    app.ProtocolDropDown.Items = protocolName;
                end
            end
            set(app.UIFigure, 'Visible', 'on')
        end

        % Button pushed function: EDFfilesButton
        function EDFfilesButtonPushed(app, event)
            [files, pathname] = uigetfile('*.edf', 'Select EDF files for this session', ...
                app.path, 'MultiSelect','on');
            if pathname ~=0
                app.path = pathname;
                if iscell(files)
                    nfiles = length(files);
                    app.SEEGfile= cell(nfiles,1);
                    for n=1:nfiles
                        app.SEEGfile{n,1} = fullfile(pathname, files{n});
                    end
                else
                    app.SEEGfile = {fullfile(pathname, files)};
                end
                if ~isempty(app.ppb.seeg.numBlock)
                    [app.ppb, TrigChan] = prepare_seeg_data(app.SEEGfile, app.ppb, 'trigger');
                    app.TriggerTypeonSEEGDropDown.Items = TrigChan;
                end
                app.Label.Text = join(files, ';');
            end
            drawnow;
            figure(app.UIFigure)
            set(app.PreprocessingEDFFilePanel, 'Enable', 'on')
        end

        % Value changed function: NumberofblockinedffilesEditField
        function NumberofblockinedffilesEditFieldValueChanged(app, event)
            app.ppb.seeg.numBlock = app.NumberofblockinedffilesEditField.Value;
            if ~isempty(app.SEEGfile) && length(app.SEEGfile) >= 1
                [app.ppb, TrigChan] = prepare_seeg_data(app.SEEGfile, app.ppb, 'trigger');
                app.TriggerTypeonSEEGDropDown.Items = TrigChan;
                app.Label.Text = app.ppb.seeg.filename;
            end
        end

        % Drop down opening function: TriggerTypeonSEEGDropDown
        function TriggerTypeonSEEGDropDownOpening(app, event)
            if isempty(app.TriggerTypeonSEEGDropDown.Items)
                [app.ppb, TrigChan] = prepare_seeg_data(app.SEEGfile, app.ppb, 'trigger');
                app.TriggerTypeonSEEGDropDown.Items = TrigChan;
                app.Label.Text = app.ppb.seeg.filename;
            end
        end

        % Button pushed function: SessionBehaviordataButton
        function SessionBehaviordataButtonPushed(app, event)
            [file, pathname] = uigetfile({'*.xlsx'; '*.xls'; '*.csv'}, ...
                'Select session Behavior file', ...
                app.path);
            if pathname ~=0
                app.ppb.behavior.filename = fullfile(pathname, file);
                app.Label_2.Text = file;
            end
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: ProtocolfileButton
        function ProtocolfileButtonPushed(app, event)
            [file, pathname] = uigetfile({'*.xlsx'; '*.xls'; '*.csv'}, ...
                'Select Protocol file', ...
                app.path);
            if pathname ~=0
                app.ppb.protocol.filename = fullfile(pathname, file);
                app.Label_3.Text = file;
            end
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: AlignMarkersButton
        function AlignMarkersButtonPushed(app, event)
            %ADD A PROGRESS BAR
            progBar = waitbar(0, 'Please wait alignement in progress ...');
            % Get EMU ID
            app.ppb.emuID = app.EMUIDEditField.Value;
            % Check if there is everything
            if isempty(app.ppb.protocol.filename)
                errordlg("Protocol file is missing")
                return
            elseif isempty(app.ppb.seeg.filename)
                errordlg("SEEG file(s) are missing")
                return
            elseif isempty(app.ppb.behavior.filename)
                errordlg("Behavior file is missing")
                return
            end
            if isempty(app.ppb.seeg.trigChan)
                app.ppb.seeg.trigChan = app.TriggerTypeonSEEGDropDown.Value;
            end
            % Make sure the trigger channel correspond to the protocol value
            [app.ppb, error] = read_protocol_file(app.ppb);
            if error
                close(progBar);
                return
            end
            [app.ppb, error] = prepare_seeg_data(app.SEEGfile, app.ppb, 'compare');
            if error
                close(progBar);
                return
            end
            waitbar(0.2, progBar, 'Please wait, alignement in progress ...')
            try
                [app.ppb, error] = align_seeg_triggers(app.ppb, progBar);
                if error
                    close(progBar);
                    return
                end
            catch ME
                close(progBar);
                msgText = getReport(ME,'extended','hyperlinks','off');
                errordlg(msgText)
                return
            end
            
        end

        % Value changed function: ProtocolDropDown
        function ProtocolDropDownValueChanged(app, event)
            value = app.ProtocolDropDown.Value;
            listing = dir(app.ppb.protocol.directory);
            idx = find(contains({listing.name}, string(value)) & endsWith({listing.name}, '.xlsx'));
            app.ppb.protocol.filename = fullfile(app.ppb.protocol.directory, listing(idx).name);
        end

        % Value changed function: TriggerTypeonSEEGDropDown
        function TriggerTypeonSEEGDropDownValueChanged(app, event)
            value = app.TriggerTypeonSEEGDropDown.Value;
            app.ppb.seeg.trigChan = value;
        end

        % Value changed function: EMUIDEditField
        function EMUIDEditFieldValueChanged(app, event)
            value = app.EMUIDEditField.Value;
            app.ppb.emuID = value;
            app.EMUIDEditField_2.Value = value;
            set(app.EDFfilesButton, 'Enable', 'on');
        end

        % Value changed function: HighpassFilterCheckBox
        function HighpassFilterCheckBoxValueChanged(app, event)
            value = app.HighpassFilterCheckBox.Value;
            app.ppb.preprocess.HF = value;
            app.ppb.preprocess.HFvalue = app.ValueHzEditField.Value;
        end

        % Value changed function: ValueHzEditField
        function ValueHzEditFieldValueChanged(app, event)
            value = app.ValueHzEditField.Value;
            app.ppb.preprocess.HFvalue = value;
        end

        % Value changed function: NotchfilterCheckBox
        function NotchfilterCheckBoxValueChanged(app, event)
            value = app.NotchfilterCheckBox.Value;
            app.ppb.preprocess.NF = value;
            app.ppb.preprocess.NFvalue = app.ValueHzEditField_2.Value;
        end

        % Value changed function: ValueHzEditField_2
        function ValueHzEditField_2ValueChanged(app, event)
            value = app.ValueHzEditField_2.Value;
            app.ppb.preprocess.NFvalue = value;
        end

        % Button pushed function: PreprocessButton
        function PreprocessButtonPushed(app, event)
            % initiate the data
            if isempty(app.ppb.seeg.data)
                f = waitbar(0.1, 'Reading Data, please wait ...');
                [app.ppb, error] = prepare_seeg_data(app.SEEGfile, app.ppb, 'read');
                if error
                    waitbar(0.9, f, 'Error');
                    close(f);
                    return
                end
                waitbar(1, f, 'Data are ready for preprocessing.');
                close(f);
            end
            
            % Save as edf
            answer = questdlg('Do you want to save edf file of preprocessing without Bad Channels?', ...
                'Saving files', 'Yes', 'No', 'Cancel');
            try
                app.ppb = preprocess_seeg(app.ppb, answer);
            catch ME
                msgText = getReport(ME,'extended','hyperlinks','off');
                errordlg(msgText)
                return
            end
        end

        % Button pushed function: SelectBadChannelsButton
        function SelectBadChannelsButtonPushed(app, event)
            if isempty(app.ppb.seeg.data)
                f = waitbar(0.1, 'Reading Data, please wait ...');
                [app.ppb, error] = prepare_seeg_data(app.SEEGfile, app.ppb, 'read');
                if error
                    waitbar(0.9, f, 'Error');
                    close(f);
                    return
                end
                waitbar(1, f, 'Data are ready for visualization.');
                close(f);
            end
            myapp = selectBadChannels(app.ppb, 'bad');
            while myapp.done == 0
                pause(0.05)
            end
            app.ppb.preprocess.badChannels  = myapp.ppb.preprocess.badChannels;
            myapp.closeTheWindow;
        end

        % Value changed function: UsethefilefromPreprocessingCheckBox
        function UsethefilefromPreprocessingCheckBoxValueChanged(app, event)
            value = app.UsethefilefromPreprocessingCheckBox.Value;
            if value == 1 
                if ~isempty(app.ppb.preprocess.data)
                    app.ppb.process.data = app.ppb.preprocess.data;
                    app.ppb.process.hdr = app.ppb.preprocess.hdr;
                    app.ppb.process.markers = app.ppb.seeg.markers;
                    % read the markers and give it to drop down 
                    markersName = unique(app.ppb.process.markers.name);
                    app.ppb.process.filename = app.ppb.preprocess.filename;
                    app.turn_process_panel('on');
                    set(app.BrowseInputButton, 'Enable', 'off');
                    app.TimeLockDropDown.Items = markersName;
                else
                    warndlg('There is no value from preprocessing !!!', 'No preprocess');
                    app.UsethefilefromPreprocessingCheckBox.Value = 0;
                end
            elseif value ==0
                app.ppb.process.data = [];
                app.ppb.process.hdr = [];
                app.ppb.process.markers = [];
                app.ppb.process.filename = [];
                app.turn_process_panel('off');
                set(app.BrowseInputButton, 'Enable', 'on');
            end
        end

        % Button pushed function: BrowseInputButton
        function BrowseInputButtonPushed(app, event)
            [file, pathname] = uigetfile('*.edf', 'Select EDF file for this session', ...
                app.path);
            if pathname == 0
                return
            end
            app.ppb.process.filename = fullfile(pathname, file);
            app.ProcessEDF.Text = file;
            % reading edf file
            progbar = waitbar(0, 'Reading Data ...');
            app.ppb.process.hdr = ft_read_header(app.ppb.process.filename);
            app.ppb.process.data = ft_read_data(app.ppb.process.filename);
            
            waitbar(0.8, progbar, 'Reading Markers ...');
            
            % read markers
            [~, partfile, ~] = fileparts(file);
            partfile = split(partfile, '_');
            idx = find(contains(partfile, 'EMU'));
            suffix = join(partfile(1:idx), '_');
            listing = dir(pathname);
            idmark = find(startsWith({listing.name}, suffix{1}) & endsWith({listing.name}, '_markers.csv'));
            
            if length(idmark) == 1
                app.ppb.process.markers = readtable(fullfile(listing(idmark).folder, ...
                    listing(idmark).name), 'Delimiter', ',');
                % read the markers and give it to drop down 
                markersName = unique(app.ppb.process.markers.name);
                app.TimeLockDropDown.Items = markersName;
                app.turn_process_panel('on');
            else
                app.path = pathname;
                set(app.BrowseMarkersButton, 'Visible', 'on');
                set(app.BrowseMarkersButton, 'Enable', 'on');
            end
            waitbar(1, progbar, 'Done.');
            close(progbar);
            set(app.UsethefilefromPreprocessingCheckBox, 'Enable', 'off');
            drawnow;
            figure(app.UIFigure)
        end

        % Value changed function: LowFrequencyEditField
        function LowFrequencyEditFieldValueChanged(app, event)
            value = app.LowFrequencyEditField.Value;
            if ~isfield(app.ppb.process.filter, 'Broadband')
                app.ppb.process.filter.Broadband = zeros(1,2);
            end
            app.ppb.process.filter.Broadband(1) = str2double(value);
        end

        % Value changed function: HighFrequencyEditField
        function HighFrequencyEditFieldValueChanged(app, event)
            value = app.HighFrequencyEditField.Value;
            if ~isfield(app.ppb.process.filter, 'Broadband')
                app.ppb.process.filter.Broadband = zeros(1,2);
            end
            app.ppb.process.filter.Broadband(2) = str2double(value);
        end

        % Button pushed function: RunButtonFDM
        function RunButtonFDMPushed(app, event)
            % Create wait Bar
            progBar = waitbar(0, 'Please wait ...');
            % Check the filtering to do
            if app.DeltaCheckBox.Value == 1
                app.get_multiband_filter('Delta');
            elseif isfield(app.ppb.process.filter, 'Delta')
                app.ppb.process.filter = rmfield(app.ppb.process.filter, 'Delta');
            end
            if app.ThetaCheckBox.Value == 1
                app.get_multiband_filter('Theta');
            elseif isfield(app.ppb.process.filter, 'Theta')
                app.ppb.process.filter = rmfield(app.ppb.process.filter, 'Theta');
            end
            if app.AlphaCheckBox.Value == 1
                app.get_multiband_filter('Alpha');
            elseif isfield(app.ppb.process.filter, 'Alpha')
                app.ppb.process.filter = rmfield(app.ppb.process.filter, 'Alpha');
            end
            if app.BetaCheckBox.Value == 1
                app.get_multiband_filter('Beta');
            elseif isfield(app.ppb.process.filter, 'Beta')
                app.ppb.process.filter = rmfield(app.ppb.process.filter, 'Beta');
            end
            if app.GammaCheckBox.Value == 1
                app.get_multiband_filter('Gamma');
            elseif isfield(app.ppb.process.filter, 'Gamma')
                app.ppb.process.filter = rmfield(app.ppb.process.filter, 'Gamma');
            end
            if app.RippleCheckBox.Value == 1
                app.get_multiband_filter('Ripple');
            elseif isfield(app.ppb.process.filter, 'Ripple')
                app.ppb.process.filter = rmfield(app.ppb.process.filter, 'Ripple');
            end
            if app.FastRippleCheckBox.Value == 1
                app.get_multiband_filter('FR');
            elseif isfield(app.ppb.process.filter, 'FR')
                app.ppb.process.filter = rmfield(app.ppb.process.filter, 'FR');
            end
            % Checking broadband
            if isfield(app.ppb.process.filter, 'Broadband') && any(app.ppb.process.filter.Broadband == 0)
                app.ppb.process.filter = rmfield(app.ppb.process.filter, 'Broadband');
            end
            % Chech the downsampling
            if app.DownsampleCheckBox.Value == 1
                value = app.DropDownDownsample.Value;
                value = split(value, " ");
                downValue = str2double(extract(value(2), digitsPattern));
                app.ppb.process.downsample = downValue;
                warndlg(sprintf("By downsampling at %d, the maximum frequency analysed is %d. Don't forget to take into account also the edge effect", ...
                    downValue, downValue/2),...
                    'Warning: DownSampling');
            else
                app.ppb.process.downsample = [];
            end
            % Check the montage
            if app.BipolarButton.Value == 1
                app.ppb.process.montage = "bipolar";
            elseif app.AverageCommonButton.Value == 1
                app.ppb.process.montage = "average";
            else
                app.ppb.process.montage = [];
            end

            % Do the process
            waitbar(0.3, progBar, 'Process running ...');
            try
                app.ppb = process_seeg(app.ppb, progBar);
            catch ME
                close(progBar);
                msgText = getReport(ME,'extended','hyperlinks','off');
                errordlg(msgText)
                return
            end

            % Create the drop down for epoch
            fields = fieldnames(app.ppb.process.outdata);
            app.DataDropDown.Items = fields;
            set(app.EpochsPanel, 'Enable', 'on');
        end

        % Button pushed function: BrowseMarkersButton
        function BrowseMarkersButtonPushed(app, event)
            [file, pathname] = uigetfile('*.csv', 'Select Markers file for this SEEG', ...
                app.path);
            if pathname == 0
                return
            end
            filename = fullfile(pathname, file);
            app.ppb.process.markers = readtable(filename, 'Delimiter', ',');

            % read the markers and give it to drop down 
            markersName = unique(app.ppb.process.markers.name);
            app.TimeLockDropDown.Items = markersName;

            app.turn_process_panel('on');
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
             % initiate value
            app.ppb.process.epochs.outdata = app.DataDropDown.Value;
            app.ppb.process.epochs.timeMarker = app.TimeLockDropDown.Value;
            app.ppb.process.epochs.average = app.AverageCheckBox.Value;
            app.ppb.process.epochs.timeBefore = app.TimebeforemarkermsEditField.Value;
            app.ppb.process.epochs.timeAfter = app.TimeaftermarkermsEditField.Value;
            
            if app.NormalizationCheckBox.Value == 1
                app.ppb.process.normalize = 1;
            else
                app.ppb.process.normalize = [];
            end
            
            % get the epochs
            try
                app.ppb = epochs_data(app.ppb);
            catch ME
                msgText = getReport(ME,'extended','hyperlinks','off');
                errordlg(msgText)
                return
            end

            % Initiate the value for TF
            fields = fieldnames(app.ppb.process.epochs.results);
            app.DataDropDownTF.Items = fields;
            app.DropDownConnectivity.Items = fields;
            markType = [];
            nsamp = [];
            for f= 1:length(fields)
                markField = fieldnames(app.ppb.process.epochs.results.(fields{f}));
                markType = [markType; markField(:)];
                nsampF = cellfun(@(x) length(app.ppb.process.epochs.results.(fields{f}).(x).data.time), ...
                 markField, 'UniformOutput',false);
                nsamp = [nsamp; nsampF(:)];
            end
            app.TimeLockDropDownTF.Items = unique(markType);
            app.TimeLockDropDownConnectivity.Items = unique(markType);

            % Initiate the highest value
            % fs = app.ppb.process.outdata.(fields{1}).fsample;
            fs = cellfun(@(x) app.ppb.process.outdata.(x).fsample, fields, 'UniformOutput',false);
            nval = min([round(cell2mat(nsamp)./2)' round(cell2mat(fs)./2)']);
            minFreq = cellfun(@(x) app.ppb.process.outdata.(x).filtervalue(end), fields, 'UniformOutput',false);
            minFreq = min(cell2mat(minFreq));
            if nval > minFreq
                nval = minFreq;
            end

            app.HighestFrequencyEditField.Value = nval;
            set(app.TimeFrequencyAnalysisPanel, 'Enable', 'on');
            set(app.ConnectivityPanel, 'Enable', 'on');
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            field = app.DataDropDown.Value;
            marker = app.TimeLockDropDown.Value;
            data = app.ppb.process.epochs.results.(field).(marker).data;
            myapp = visualize(data, 'epochs');
            while myapp.done == 0
                pause(0.05)
            end
            myapp.closeWindow;
        end

        % Button pushed function: RunButtonTF
        function RunButtonTFPushed(app, event)
            app.ppb.process.TF.outdata = app.DataDropDownTF.Value;
            app.ppb.process.TF.timeMarker = app.TimeLockDropDownTF.Value;
            app.ppb.process.TF.psd = app.PowerSpectralDensityCheckBox.Value;
            app.ppb.process.TF.map = app.TimeFrequencyMapCheckBox.Value;
            app.ppb.process.TF.mapHg = app.HighestFrequencyEditField.Value;

            if app.NormalizationTFCheckBox.Value == 1
                app.ppb.process.normalizeTF = 1;
            else
                app.ppb.process.normalizeTF = [];
            end
            try
                app.ppb = frequency_data(app.ppb);
            catch ME
                msgText = getReport(ME,'extended','hyperlinks','off');
                errordlg(msgText)
                return
            end
        end

        % Button pushed function: VisualizeButtonTF
        function VisualizeButtonTFPushed(app, event)
            field = app.DataDropDownTF.Value;
            marker = app.TimeLockDropDownTF.Value;
            if ~isfield(app.ppb.process.TF.results, field) || ~isfield(app.ppb.process.TF.results.(field), marker)
                errordlg(sprintf('%s - %s: TF analysis doesn"t exist.', field, marker))
                return
            end
            data = app.ppb.process.TF.results.(field).(marker).data;
            myapp = visualize(data, 'TF');
            while myapp.done == 0
                pause(0.05)
            end
            myapp.closeWindow;
        end

        % Value changed function: OverwriteOutputfilesCheckBox
        function OverwriteOutputfilesCheckBoxValueChanged(app, event)
            value = app.OverwriteOutputfilesCheckBox.Value;
            app.ppb.process.overwrite = value;
        end

        % Value changed function: DataDropDownTF
        function DataDropDownTFValueChanged(app, event)
            value = app.DataDropDownTF.Value;
            markField = fieldnames(app.ppb.process.epochs.results.(value));
            app.TimeLockDropDownTF.Items = markField;

            % Initiate the highest value
            fs = app.ppb.process.outdata.(value).fsample;
            nsamp = length(app.ppb.process.epochs.results.(value).(markField{1}).data.time);
            nval = min([round(nsamp/2) round(fs/2)]);
            if nval > app.ppb.process.filter.(value)(end)
                nval = app.ppb.process.filter.(value)(end);
            end

            app.HighestFrequencyEditField.Value = nval;
        end

        % Value changed function: EMUIDEditField_2
        function EMUIDEditField_2ValueChanged(app, event)
            value = app.EMUIDEditField_2.Value;
            app.ppb.emuID = value;
        end

        % Button pushed function: TrialsselectionButton
        function TrialsselectionButtonPushed(app, event)
            field = app.DataDropDown.Value;
            marker = app.TimeLockDropDown.Value;
            data = app.ppb.process.epochs.results.(field).(marker).data;
            myapp = TrialsSelection(data);
            while myapp.done == 0
                pause(0.05)
            end
            data = myapp.dataSelect;
            if ~app.AverageCheckBox.Value
                data.avg = zeros(length(data.label), length(data.time));
            end
            app.ppb.process.epochs.results.(field).(marker).data = data;
            myapp.closeTheWindow;
            % save again overwrite
            save(app.ppb.process.epochs.results.(field).(marker).filename , "data", '-v7.3');
        end

        % Value changed function: AverageCheckBox
        function AverageCheckBoxValueChanged(app, event)
            value = app.AverageCheckBox.Value;
            field = app.DataDropDown.Value;
            marker = app.TimeLockDropDown.Value;
            if isfield(app.ppb.process.epochs.results, field)
                if isfield(app.ppb.process.epochs.results.(field), marker)
                    if isfield(app.ppb.process.epochs.results.(field).(marker), 'data') && ...
                            ~isempty(app.ppb.process.epochs.results.(field).(marker).data)
                        data = app.ppb.process.epochs.results.(field).(marker).data;
                        avg = zeros(length(data.label), length(data.time));
                        if value
                            for i=1:length(data.trial)
                                avg = avg + data.trial{i};
                            end
                            avg = avg ./ length(data.trial);
                        end

                        app.ppb.process.epochs.results.(field).(marker).data.avg = avg;
                    end
                end
            end
        end

        % Button pushed function: VisualizeProcessButton
        function VisualizeProcessButtonPushed(app, event)
            myapp = selectBadChannels(app.ppb, 'process');
            while myapp.done == 0
                pause(0.05)
            end
            myapp.closeTheWindow;
        end

        % Value changed function: TimeLockDropDownTF
        function TimeLockDropDownTFValueChanged(app, event)
            value = app.TimeLockDropDownTF.Value;
            % Initiate the highest value
            fs = app.ppb.process.outdata.(app.DataDropDownTF.Value).fsample;
            nsamp = app.ppb.process.epochs.results.(app.DataDropDownTF.Value).(value).time;
            nval = min([round(nsamp/2) round(fs/2)]);
            if nval > app.ppb.process.filter.(app.DataDropDownTF.Value)(end)
                nval = app.ppb.process.filter.(app.DataDropDownTF.Value)(end);
            end

            app.HighestFrequencyEditField.Value = nval;
        end

        % Button pushed function: RunButtonConnectivity
        function RunButtonConnectivityPushed(app, event)
            app.ppb.process.connectivity.outdata = app.DropDownConnectivity.Value;
            app.ppb.process.connectivity.timeMarker = app.TimeLockDropDownConnectivity.Value;
            app.ppb.process.connectivity.method = app.MethodsDropDown.Value;

            try
                app.ppb = connectivity_data(app.ppb);
            catch ME
                msgText = getReport(ME,'extended','hyperlinks','off');
                errordlg(msgText)
                return
            end
        end

        % Button pushed function: VisualizeButtonConnectivity
        function VisualizeButtonConnectivityPushed(app, event)
            field = app.DropDownConnectivity.Value;
            marker = app.TimeLockDropDownConnectivity.Value;
            data = app.ppb.process.connectivity.results.(field).(marker).data;
            myapp = visualize(data, 'connectivity');
            while myapp.done == 0
                pause(0.05)
            end
            myapp.closeWindow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.PreprocessingTab.Tag = 'HPvalue';
            app.FilesPanel = uipanel(app.PreprocessingTab);
            app.FilesPanel.Title = 'Files';
            app.EDFfilesButton = uibutton(app.FilesPanel, 'push');
            app.EDFfilesButton.ButtonPushedFcn = createCallbackFcn(app, @EDFfilesButtonPushed, true);
            app.EDFfilesButton.Enable = 'off';
            app.EDFfilesButton.Text = 'EDF files';
            app.SessionBehaviordataButton = uibutton(app.FilesPanel, 'push');
            app.SessionBehaviordataButton.ButtonPushedFcn = createCallbackFcn(app, @SessionBehaviordataButtonPushed, true);
            app.SessionBehaviordataButton.Tag = 'behav';
            app.SessionBehaviordataButton.Text = 'Session Behavior data';
            app.ProtocolDropDownLabel = uilabel(app.FilesPanel);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.ProtocolDropDown = uidropdown(app.FilesPanel);
            app.ProtocolDropDown.Items = {};
            app.ProtocolDropDown.ValueChangedFcn = createCallbackFcn(app, @ProtocolDropDownValueChanged, true);
            app.ProtocolDropDown.Value = {};
            app.Label = uilabel(app.FilesPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.FilesPanel);
            app.Label_2.Text = '';
            app.EMUIDEditFieldLabel = uilabel(app.FilesPanel);
            app.EMUIDEditFieldLabel.Text = 'EMU ID';
            app.EMUIDEditField = uieditfield(app.FilesPanel, 'text');
            app.EMUIDEditField.CharacterLimits = [0 3];
            app.EMUIDEditField.InputType = 'digits';
            app.EMUIDEditField.ValueChangedFcn = createCallbackFcn(app, @EMUIDEditFieldValueChanged, true);
            app.AlignMarkersButton = uibutton(app.FilesPanel, 'push');
            app.AlignMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @AlignMarkersButtonPushed, true);
            app.AlignMarkersButton.Text = 'Align Markers';
            app.NumberofblockinedffilesEditFieldLabel = uilabel(app.FilesPanel);
            app.NumberofblockinedffilesEditFieldLabel.Text = 'Number of block in edf files';
            app.NumberofblockinedffilesEditField = uieditfield(app.FilesPanel, 'text');
            app.NumberofblockinedffilesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofblockinedffilesEditFieldValueChanged, true);
            app.TriggerTypeonSEEGDropDownLabel = uilabel(app.FilesPanel);
            app.TriggerTypeonSEEGDropDownLabel.Text = 'Trigger Type on SEEG ';
            app.TriggerTypeonSEEGDropDown = uidropdown(app.FilesPanel);
            app.TriggerTypeonSEEGDropDown.Items = {};
            app.TriggerTypeonSEEGDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TriggerTypeonSEEGDropDownOpening, true);
            app.TriggerTypeonSEEGDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerTypeonSEEGDropDownValueChanged, true);
            app.TriggerTypeonSEEGDropDown.Value = {};
            app.ProtocolfileButton = uibutton(app.FilesPanel, 'push');
            app.ProtocolfileButton.ButtonPushedFcn = createCallbackFcn(app, @ProtocolfileButtonPushed, true);
            app.ProtocolfileButton.Text = 'Protocol file';
            app.Label_3 = uilabel(app.FilesPanel);
            app.Label_3.Text = '';
            app.PreprocessingEDFFilePanel = uipanel(app.PreprocessingTab);
            app.PreprocessingEDFFilePanel.Enable = 'off';
            app.PreprocessingEDFFilePanel.Title = 'Preprocessing EDF File';
            app.HighpassFilterCheckBox = uicheckbox(app.PreprocessingEDFFilePanel);
            app.HighpassFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighpassFilterCheckBoxValueChanged, true);
            app.HighpassFilterCheckBox.Text = 'High-pass Filter';
            app.ValueHzEditFieldLabel = uilabel(app.PreprocessingEDFFilePanel);
            app.ValueHzEditFieldLabel.Text = 'Value (Hz)';
            app.ValueHzEditField = uieditfield(app.PreprocessingEDFFilePanel, 'numeric');
            app.ValueHzEditField.ValueChangedFcn = createCallbackFcn(app, @ValueHzEditFieldValueChanged, true);
            app.ValueHzEditField.Value = 0.5;
            app.NotchfilterCheckBox = uicheckbox(app.PreprocessingEDFFilePanel);
            app.NotchfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchfilterCheckBoxValueChanged, true);
            app.NotchfilterCheckBox.Text = 'Notch filter';
            app.ValueHzEditField_2Label = uilabel(app.PreprocessingEDFFilePanel);
            app.ValueHzEditField_2Label.Text = 'Value (Hz)';
            app.ValueHzEditField_2 = uieditfield(app.PreprocessingEDFFilePanel, 'numeric');
            app.ValueHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @ValueHzEditField_2ValueChanged, true);
            app.ValueHzEditField_2.Tag = 'NFvalue';
            app.ValueHzEditField_2.Value = 60;
            app.PreprocessButton = uibutton(app.PreprocessingEDFFilePanel, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.SelectBadChannelsButton = uibutton(app.PreprocessingEDFFilePanel, 'push');
            app.SelectBadChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBadChannelsButtonPushed, true);
            app.SelectBadChannelsButton.Text = 'Select Bad Channels';
            app.Image = uiimage(app.PreprocessingTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PreProcB_logo_fb.png');
            app.version131Label = uilabel(app.PreprocessingTab);
            app.version131Label.Text = 'version 1.3.1';
            app.ProcesssingTab = uitab(app.TabGroup);
            app.ProcesssingTab.Title = 'Processsing';
            app.EpochsPanel = uipanel(app.ProcesssingTab);
            app.EpochsPanel.Enable = 'off';
            app.EpochsPanel.Title = 'Epochs';
            app.TimeLockDropDownLabel = uilabel(app.EpochsPanel);
            app.TimeLockDropDownLabel.Text = 'Time Lock';
            app.TimeLockDropDown = uidropdown(app.EpochsPanel);
            app.AverageCheckBox = uicheckbox(app.EpochsPanel);
            app.AverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageCheckBoxValueChanged, true);
            app.AverageCheckBox.Text = 'Average';
            app.TimebeforemarkermsEditFieldLabel = uilabel(app.EpochsPanel);
            app.TimebeforemarkermsEditFieldLabel.Text = 'Time before marker (ms)';
            app.TimebeforemarkermsEditField = uieditfield(app.EpochsPanel, 'numeric');
            app.TimeaftermarkermsEditFieldLabel = uilabel(app.EpochsPanel);
            app.TimeaftermarkermsEditFieldLabel.Text = 'Time after marker (ms)';
            app.TimeaftermarkermsEditField = uieditfield(app.EpochsPanel, 'numeric');
            app.RunButton = uibutton(app.EpochsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.VisualizeButton = uibutton(app.EpochsPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.DataDropDownLabel = uilabel(app.EpochsPanel);
            app.DataDropDownLabel.Text = 'Data ';
            app.DataDropDown = uidropdown(app.EpochsPanel);
            app.DataDropDown.Items = {};
            app.DataDropDown.Value = {};
            app.NormalizationCheckBox = uicheckbox(app.EpochsPanel);
            app.NormalizationCheckBox.Text = 'Normalization';
            app.TrialsselectionButton = uibutton(app.EpochsPanel, 'push');
            app.TrialsselectionButton.ButtonPushedFcn = createCallbackFcn(app, @TrialsselectionButtonPushed, true);
            app.TrialsselectionButton.Text = 'Trials selection';
            app.TimeFrequencyAnalysisPanel = uipanel(app.ProcesssingTab);
            app.TimeFrequencyAnalysisPanel.Enable = 'off';
            app.TimeFrequencyAnalysisPanel.Title = 'Time - Frequency Analysis';
            app.PowerSpectralDensityCheckBox = uicheckbox(app.TimeFrequencyAnalysisPanel);
            app.PowerSpectralDensityCheckBox.Text = 'Power Spectral Density';
            app.TimeFrequencyMapCheckBox = uicheckbox(app.TimeFrequencyAnalysisPanel);
            app.TimeFrequencyMapCheckBox.Text = 'Time Frequency Map';
            app.RunButtonTF = uibutton(app.TimeFrequencyAnalysisPanel, 'push');
            app.RunButtonTF.ButtonPushedFcn = createCallbackFcn(app, @RunButtonTFPushed, true);
            app.RunButtonTF.Text = 'Run';
            app.VisualizeButtonTF = uibutton(app.TimeFrequencyAnalysisPanel, 'push');
            app.VisualizeButtonTF.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonTFPushed, true);
            app.VisualizeButtonTF.Text = 'Visualize';
            app.TimeLockDropDown_2Label = uilabel(app.TimeFrequencyAnalysisPanel);
            app.TimeLockDropDown_2Label.Text = 'Time Lock';
            app.TimeLockDropDownTF = uidropdown(app.TimeFrequencyAnalysisPanel);
            app.TimeLockDropDownTF.ValueChangedFcn = createCallbackFcn(app, @TimeLockDropDownTFValueChanged, true);
            app.DataDropDown_2Label = uilabel(app.TimeFrequencyAnalysisPanel);
            app.DataDropDown_2Label.Text = 'Data ';
            app.DataDropDownTF = uidropdown(app.TimeFrequencyAnalysisPanel);
            app.DataDropDownTF.Items = {};
            app.DataDropDownTF.ValueChangedFcn = createCallbackFcn(app, @DataDropDownTFValueChanged, true);
            app.DataDropDownTF.Value = {};
            app.HighestFrequencyHzEditFieldLabel = uilabel(app.TimeFrequencyAnalysisPanel);
            app.HighestFrequencyHzEditFieldLabel.Text = 'Highest Frequency (Hz)';
            app.HighestFrequencyEditField = uieditfield(app.TimeFrequencyAnalysisPanel, 'numeric');
            app.NormalizationTFCheckBox = uicheckbox(app.TimeFrequencyAnalysisPanel);
            app.NormalizationTFCheckBox.Text = 'Normalization';
            app.FilteringDownsamplingMontagePanel = uipanel(app.ProcesssingTab);
            app.FilteringDownsamplingMontagePanel.Enable = 'off';
            app.FilteringDownsamplingMontagePanel.Title = 'Filtering & Downsampling & Montage';
            app.BroadbandanalysisLabel = uilabel(app.FilteringDownsamplingMontagePanel);
            app.BroadbandanalysisLabel.Text = 'Broadband analysis:';
            app.LowFrequencyEditFieldLabel = uilabel(app.FilteringDownsamplingMontagePanel);
            app.LowFrequencyEditFieldLabel.Text = 'Low Frequency';
            app.LowFrequencyEditField = uieditfield(app.FilteringDownsamplingMontagePanel, 'text');
            app.LowFrequencyEditField.InputType = 'digits';
            app.LowFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @LowFrequencyEditFieldValueChanged, true);
            app.HighFrequencyEditFieldLabel = uilabel(app.FilteringDownsamplingMontagePanel);
            app.HighFrequencyEditFieldLabel.Text = 'High Frequency';
            app.HighFrequencyEditField = uieditfield(app.FilteringDownsamplingMontagePanel, 'text');
            app.HighFrequencyEditField.InputType = 'digits';
            app.HighFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @HighFrequencyEditFieldValueChanged, true);
            app.DownsampleCheckBox = uicheckbox(app.FilteringDownsamplingMontagePanel);
            app.DownsampleCheckBox.Text = 'Downsample';
            app.DropDownDownsample = uidropdown(app.FilteringDownsamplingMontagePanel);
            app.DropDownDownsample.Items = {};
            app.DropDownDownsample.Value = {};
            app.MontageButtonGroup = uibuttongroup(app.FilteringDownsamplingMontagePanel);
            app.MontageButtonGroup.Title = 'Montage';
            app.BipolarButton = uiradiobutton(app.MontageButtonGroup);
            app.BipolarButton.Text = 'Bipolar';
            app.BipolarButton.Value = true;
            app.AverageCommonButton = uiradiobutton(app.MontageButtonGroup);
            app.AverageCommonButton.Text = 'Average Common';
            app.MonopolarButton = uiradiobutton(app.MontageButtonGroup);
            app.MonopolarButton.Text = 'Monopolar';
            app.MultiBandfilterPanel = uipanel(app.FilteringDownsamplingMontagePanel);
            app.MultiBandfilterPanel.Title = 'Multi-Band filter';
            app.GridLayout = uigridlayout(app.MultiBandfilterPanel);
            app.DeltaCheckBox = uicheckbox(app.GridLayout);
            app.DeltaCheckBox.Text = 'Delta';
            app.ThetaCheckBox = uicheckbox(app.GridLayout);
            app.ThetaCheckBox.Text = 'Theta';
            app.AlphaCheckBox = uicheckbox(app.GridLayout);
            app.AlphaCheckBox.Text = 'Alpha';
            app.BetaCheckBox = uicheckbox(app.GridLayout);
            app.BetaCheckBox.Text = 'Beta';
            app.GammaCheckBox = uicheckbox(app.GridLayout);
            app.GammaCheckBox.Text = 'Gamma';
            app.RippleCheckBox = uicheckbox(app.GridLayout);
            app.RippleCheckBox.Text = 'Ripple';
            app.FastRippleCheckBox = uicheckbox(app.GridLayout);
            app.FastRippleCheckBox.Text = 'Fast Ripple';
            app.LFDelta = uieditfield(app.GridLayout, 'numeric');
            app.LFDelta.Value = 1;
            app.HFDelta = uieditfield(app.GridLayout, 'numeric');
            app.HFDelta.Value = 4;
            app.LFTheta = uieditfield(app.GridLayout, 'numeric');
            app.LFTheta.Value = 4;
            app.HFTheta = uieditfield(app.GridLayout, 'numeric');
            app.HFTheta.Value = 8;
            app.LFAlpha = uieditfield(app.GridLayout, 'numeric');
            app.LFAlpha.Value = 8;
            app.HFAlpha = uieditfield(app.GridLayout, 'numeric');
            app.HFAlpha.Value = 12;
            app.LFBeta = uieditfield(app.GridLayout, 'numeric');
            app.LFBeta.Value = 12;
            app.HFBeta = uieditfield(app.GridLayout, 'numeric');
            app.HFBeta.Value = 30;
            app.LFGamma = uieditfield(app.GridLayout, 'numeric');
            app.LFGamma.Value = 30;
            app.HFGamma = uieditfield(app.GridLayout, 'numeric');
            app.HFGamma.Value = 80;
            app.LFRipple = uieditfield(app.GridLayout, 'numeric');
            app.LFRipple.Value = 80;
            app.HFRipple = uieditfield(app.GridLayout, 'numeric');
            app.HFRipple.Value = 250;
            app.LFFR = uieditfield(app.GridLayout, 'numeric');
            app.LFFR.Value = 250;
            app.HFFR = uieditfield(app.GridLayout, 'numeric');
            app.HFFR.Value = 500;
            app.RunButtonFDM = uibutton(app.FilteringDownsamplingMontagePanel, 'push');
            app.RunButtonFDM.ButtonPushedFcn = createCallbackFcn(app, @RunButtonFDMPushed, true);
            app.RunButtonFDM.Text = 'Run';
            app.VisualizeProcessButton = uibutton(app.FilteringDownsamplingMontagePanel, 'push');
            app.VisualizeProcessButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeProcessButtonPushed, true);
            app.VisualizeProcessButton.Text = 'Visualize';
            app.FilesManagementInputOutputPanel = uipanel(app.ProcesssingTab);
            app.FilesManagementInputOutputPanel.Title = 'Files Management - Input & Output';
            app.OverwriteOutputfilesCheckBox = uicheckbox(app.FilesManagementInputOutputPanel);
            app.OverwriteOutputfilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverwriteOutputfilesCheckBoxValueChanged, true);
            app.OverwriteOutputfilesCheckBox.Text = 'Overwrite Output files';
            app.UsethefilefromPreprocessingCheckBox = uicheckbox(app.FilesManagementInputOutputPanel);
            app.UsethefilefromPreprocessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsethefilefromPreprocessingCheckBoxValueChanged, true);
            app.UsethefilefromPreprocessingCheckBox.Text = 'Use the file from Pre-processing';
            app.BrowseInputButton = uibutton(app.FilesManagementInputOutputPanel, 'push');
            app.BrowseInputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputButtonPushed, true);
            app.BrowseInputButton.Text = 'Browse Input';
            app.ProcessEDF = uilabel(app.FilesManagementInputOutputPanel);
            app.ProcessEDF.Text = '';
            app.EMUIDEditField_2Label = uilabel(app.FilesManagementInputOutputPanel);
            app.EMUIDEditField_2Label.Text = 'EMU ID';
            app.EMUIDEditField_2 = uieditfield(app.FilesManagementInputOutputPanel, 'text');
            app.EMUIDEditField_2.CharacterLimits = [0 3];
            app.EMUIDEditField_2.InputType = 'digits';
            app.EMUIDEditField_2.ValueChangedFcn = createCallbackFcn(app, @EMUIDEditField_2ValueChanged, true);
            app.BrowseMarkersButton = uibutton(app.FilesManagementInputOutputPanel, 'push');
            app.BrowseMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseMarkersButtonPushed, true);
            app.BrowseMarkersButton.Enable = 'off';
            app.BrowseMarkersButton.Visible = 'off';
            app.BrowseMarkersButton.Text = 'Browse Markers';
            app.ConnectivityPanel = uipanel(app.ProcesssingTab);
            app.ConnectivityPanel.Enable = 'off';
            app.ConnectivityPanel.Title = 'Connectivity';
            app.MethodsDropDownLabel = uilabel(app.ConnectivityPanel);
            app.MethodsDropDownLabel.Text = 'Methods';
            app.MethodsDropDown = uidropdown(app.ConnectivityPanel);
            app.MethodsDropDown.Items = {'cross-correlation'};
            app.MethodsDropDown.Value = 'cross-correlation';
            app.RunButtonConnectivity = uibutton(app.ConnectivityPanel, 'push');
            app.RunButtonConnectivity.ButtonPushedFcn = createCallbackFcn(app, @RunButtonConnectivityPushed, true);
            app.RunButtonConnectivity.Text = 'Run';
            app.DataLabel = uilabel(app.ConnectivityPanel);
            app.DataLabel.Text = 'Data';
            app.DropDownConnectivity = uidropdown(app.ConnectivityPanel);
            app.DropDownConnectivity.Items = {};
            app.DropDownConnectivity.Value = {};
            app.TimeLockDropDown_2Label_2 = uilabel(app.ConnectivityPanel);
            app.TimeLockDropDown_2Label_2.Text = 'Time Lock';
            app.TimeLockDropDownConnectivity = uidropdown(app.ConnectivityPanel);
            app.VisualizeButtonConnectivity = uibutton(app.ConnectivityPanel, 'push');
            app.VisualizeButtonConnectivity.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonConnectivityPushed, true);
            app.VisualizeButtonConnectivity.Text = 'Visualize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreProcB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end