classdef TrialsSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        DoneButton        matlab.ui.control.Button
        RejectedCheckBox  matlab.ui.control.CheckBox
        backButton        matlab.ui.control.Button
        nextButton        matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        done % Description
        dataSelect
    end
    
    properties (Access = private)
        trial % Description
        label
        time
        timeOnset
        avg
        xtime
        rejected
        idxTrial
        TSplot
        yL
        offset
        nbrTrials
        Axmarker 
    end
    
    methods (Access = private)
        
        function displayTrials(app)
            delete(app.TSplot);
            delete(app.Axmarker)
            %Create an offset to have all the channels in the same
            %plot
            if isempty(app.yL)
                app.yL = [min(app.trial{app.idxTrial}, [], 'all') max(app.trial{app.idxTrial}, [], 'all')];
            end

            nch = length(app.label);
            yInterv = app.yL(2)- app.yL(1);
            yTotal = yInterv * nch;
            app.offset = [1:nch]*yInterv - (yInterv/2);
            data = bsxfun(@plus, app.trial{app.idxTrial}(:,:), app.offset');
            
            hold(app.UIAxes, 'on');
            app.TSplot = plot(app.UIAxes, app.time, data, 'Color', 	"#0072BD");
            app.Axmarker = plot(app.UIAxes, [0.0 0.0], [app.offset(1) app.offset(end)], '-r', ...
                'LineWidth', 1);
            hold(app.UIAxes, 'off');
            xlim(app.UIAxes, [app.time(1) app.time(end)])
            set(app.UIAxes, 'ytick', app.offset ,'yticklabel',app.label)
            ylim(app.UIAxes, [0 yTotal]);
            title(app.UIAxes, strcat("trial number: ", num2str(app.idxTrial)));
        end
    end
    
    methods (Access = public)
        
        function closeTheWindow(app)
            delete(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.label = data.label;
            app.trial = data.trial;
            app.xtime = data.xtime;
            app.time = data.time;
            app.timeOnset = data.timeOnset;

            app.done = 0;
            app.idxTrial = 1;
            app.nbrTrials = size(app.trial, 2);
            app.rejected = zeros(app.nbrTrials, 1);
            app.dataSelect = [];
            app.dataSelect.label = app.label;
            app.dataSelect.fsample = data.fsample;
            app.dataSelect.time = app.time;

            app.displayTrials;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'uparrow'
                    yInterv = app.yL(2)- app.yL(1);
                    app.yL(1) = app.yL(1) + yInterv*0.05;
                    app.yL(2) = app.yL(2) - yInterv*0.05;
                    app.displayTrials;
                case 'downarrow'
                    yInterv = app.yL(2)- app.yL(1);
                    app.yL(1) = app.yL(1) - yInterv*0.05;
                    app.yL(2) = app.yL(2) + yInterv*0.05;
                    app.displayTrials;
            end
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            app.idxTrial = app.idxTrial + 1;
            if app.idxTrial > app.nbrTrials
                app.idxTrial = app.nbrTrials;
                msgbox('There is no more trials');
            end
            app.displayTrials;
            app.RejectedCheckBox.Value = app.rejected(app.idxTrial, 1);
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            app.idxTrial = app.idxTrial - 1;
            if app.idxTrial < 1
                app.idxTrial = 1;
            end
            app.displayTrials;
            app.RejectedCheckBox.Value = app.rejected(app.idxTrial, 1);
        end

        % Value changed function: RejectedCheckBox
        function RejectedCheckBoxValueChanged(app, event)
            value = app.RejectedCheckBox.Value;
            app.rejected(app.idxTrial, 1) = value;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            idx = find(app.rejected == 0);
            
            app.dataSelect.trial = app.trial(idx);
            app.dataSelect.xtime = app.xtime(idx);
            app.dataSelect.timeOnset = app.timeOnset(idx);
            app.dataSelect.avg = zeros(length(app.label), length(app.time));
            for i=idx'
                app.dataSelect.avg = app.dataSelect.avg + app.trial{i};
            end
            app.dataSelect.avg = app.dataSelect.avg ./ length(idx);

            app.done = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % delete(app)
            idx = find(app.rejected == 0);
            
            app.dataSelect.trial = app.trial(idx);
            app.dataSelect.xtime = app.xtime(idx);
            app.dataSelect.timeOnset = app.timeOnset(idx);
            app.dataSelect.avg = zeros(length(app.label), length(app.time));
            for i=idx'
                app.dataSelect.avg = app.dataSelect.avg + app.trial{i};
            end
            app.dataSelect.avg = app.dataSelect.avg ./ length(idx);

            app.done = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = '>';
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = '<';
            app.RejectedCheckBox = uicheckbox(app.UIFigure);
            app.RejectedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RejectedCheckBoxValueChanged, true);
            app.RejectedCheckBox.Text = 'Rejected';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrialsSelection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end