classdef concatenate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        DoneButton  matlab.ui.control.Button
        ConcatenateFilesChoosetheorderLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        SEEGfile % Cell giving the info on all SEEG files
        done     % To know when it's done
    end
    
    properties (Access = private)
        nfiles % Description
        figsize
        titlesize
        freespace
        panelsize
        saveButton
    end
    
    methods (Access = private)
        
        function paname = createButton(app, filename, n, trigchan)
            
            % Create a panel for te file
            paname = strcat("Panel", num2str(n));
            app.saveButton.(paname) = uipanel(app.UIFigure);
            app.saveButton.(paname).Title = filename;
            hpos = app.freespace - app.panelsize(2) - (n-1)*app.panelsize(2);
            app.saveButton.(paname).Position = [5 hpos app.panelsize(1) app.panelsize(2)];
            
            % Create the button to give the order, the start and the End
            ordername = strcat(paname, 'order');
            startname = strcat(paname, 'start');
            endname = strcat(paname, 'end');
            trigname = strcat(paname, 'trig');
            buttonsize = [(app.panelsize(1)-30)/4 app.panelsize(2)/3];

            app.saveButton.(strcat(ordername, 'Label')) = uilabel(app.saveButton.(paname));
            app.saveButton.(strcat(ordername, 'Label')).HorizontalAlignment = 'right';
            app.saveButton.(strcat(ordername, 'Label')).Position = [5 app.panelsize(2)/2  buttonsize(1)/2 buttonsize(2)];
            app.saveButton.(strcat(ordername, 'Label')).Text = 'Order';
            app.saveButton.(ordername) = uieditfield(app.saveButton.(paname), 'numeric');
            app.saveButton.(ordername).Position = [5+buttonsize(1)/2 app.panelsize(2)/2 buttonsize(1)/2 buttonsize(2)];

            app.saveButton.(strcat(startname, 'Label')) = uilabel(app.saveButton.(paname));
            app.saveButton.(strcat(startname, 'Label')).HorizontalAlignment = 'right';
            app.saveButton.(strcat(startname, 'Label')).Position = [10+buttonsize(1) app.panelsize(2)/2  buttonsize(1)/2 buttonsize(2)];
            app.saveButton.(strcat(startname, 'Label')).Text = 'Start(s)';
            app.saveButton.(startname) = uieditfield(app.saveButton.(paname), 'numeric');
            app.saveButton.(startname).Position = [10+3*buttonsize(1)/2 app.panelsize(2)/2 buttonsize(1)/2 buttonsize(2)];

            app.saveButton.(strcat(endname, 'Label')) = uilabel(app.saveButton.(paname));
            app.saveButton.(strcat(endname, 'Label')).HorizontalAlignment = 'right';
            app.saveButton.(strcat(endname, 'Label')).Position = [15+2*buttonsize(1) app.panelsize(2)/2  buttonsize(1)/2 buttonsize(2)];
            app.saveButton.(strcat(endname, 'Label')).Text = 'End(s)';
            app.saveButton.(endname) = uieditfield(app.saveButton.(paname), 'numeric');
            app.saveButton.(endname).Position = [15+5*buttonsize(1)/2 app.panelsize(2)/2 buttonsize(1)/2 buttonsize(2)];

            app.saveButton.(strcat(trigname, 'Label')) = uilabel(app.saveButton.(paname));
            app.saveButton.(strcat(trigname, 'Label')).HorizontalAlignment = 'right';
            app.saveButton.(strcat(trigname, 'Label')).Position = [20+3*buttonsize(1) app.panelsize(2)/2  buttonsize(1)/2 buttonsize(2)];
            app.saveButton.(strcat(trigname, 'Label')).Text = 'Trigger';

            % Create ProtocolDropDown
            app.saveButton.(trigname) = uidropdown(app.saveButton.(paname));
            app.saveButton.(trigname).Items = trigchan;
            app.saveButton.(trigname).Position = [20+6*buttonsize(1)/2 app.panelsize(2)/2 buttonsize(1)/2 buttonsize(2)];
            
        end
    end
    
    methods (Access = public)
        
        function closeWindow(app)
            delete(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SEEGfile)
            app.done = 0;
            app.SEEGfile = SEEGfile;
            app.nfiles = size(SEEGfile, 1);
            app.figsize = get(app.UIFigure, 'Position');
            app.saveButton = struct();
            app.titlesize = get(app.ConcatenateFilesChoosetheorderLabel, 'Position');
            app.freespace = app.titlesize(2) - 5;
            app.panelsize = [app.figsize(3)-10 app.freespace/app.nfiles];
            for s=1:app.nfiles
                paname = createButton(app, app.SEEGfile{s, 1}, s, app.SEEGfile{s, 2});
                app.SEEGfile{s, 3} = paname;
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            for n=1:app.nfiles
                app.SEEGfile{n, 4} = app.saveButton.(strcat(app.SEEGfile{n, 3}, 'order')).Value;
                app.SEEGfile{n, 5} = app.saveButton.(strcat(app.SEEGfile{n, 3}, 'start')).Value;
                app.SEEGfile{n, 6} = app.saveButton.(strcat(app.SEEGfile{n, 3}, 'end')).Value;
                app.SEEGfile{n, 7} = app.saveButton.(strcat(app.SEEGfile{n, 3}, 'trig')).Value;
            end
            app.done = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.done = 2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConcatenateFilesChoosetheorderLabel = uilabel(app.UIFigure);
            app.ConcatenateFilesChoosetheorderLabel.Text = 'Concatenate Files: Choose the order';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = concatenate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end