classdef selectBadChannels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MarkerstodisplayLabel           matlab.ui.control.Label
        WhattovisualizeLabel            matlab.ui.control.Label
        MarkerstodisplayDropDown        matlab.ui.control.DropDown
        WhattovisualizeDropDown         matlab.ui.control.DropDown
        DoneButton                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        FilterHPButton                  matlab.ui.control.Button
        WindowTime                      matlab.ui.control.NumericEditField
        WinodwTimeEditFieldLabel        matlab.ui.control.Label
        ChannelsDisplay                 matlab.ui.control.NumericEditField
        NumberofChannelsEditFieldLabel  matlab.ui.control.Label
        ButtonScrollup                  matlab.ui.control.Button
        ButtonScrolldown                matlab.ui.control.Button
        ButtonBack                      matlab.ui.control.Button
        ButtonForward                   matlab.ui.control.Button
        UIAxesTS                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fs = []; % Sampling rate
        ts
        nSamples
        time      %real time of the signal
        timeSample
        moving    %Value of moving window
        cellMovingIdx 
        cellMoving
        channel   %selected channel to visualize
        cellChaningIdx
        cellChaning
        chanSample
        LiveChannels
        ntime
        yL = []
        offset
        TSplot
        oldts
        nchan
        markers
        markerSelect
        markerplot
        type
    end
    
    properties (Access = public)
        ppb
        done
        %Number of channels
    end
    
    methods (Access = private)
        
        function update_plot(app)
            delete(app.TSplot);

            movalue = app.cellMoving(:, app.cellMovingIdx)';
            movalue(movalue == 0) = [];
            mochannel = app.cellChaning(:, app.cellChaningIdx)';
            mochannel(mochannel == 0) = [];
            app.LiveChannels = app.channel(mochannel);

            %Create an offset to have all the channels in the same
            %plot
            if isempty(app.yL)
                app.yL = [min(app.ts(mochannel, movalue), [], 'all') max(app.ts(mochannel, movalue), [], 'all')];
            end
            nch = length(mochannel);
            yInterv = app.yL(2)- app.yL(1);
            yTotal = yInterv * nch;
            app.offset = [1:nch]*yInterv - (yInterv/2);
            data = bsxfun(@plus, app.ts(mochannel, movalue), app.offset');
            
            app.TSplot = plot(app.UIAxesTS, app.time(movalue), data, 'Color', 	"#0072BD");
            xlim(app.UIAxesTS, [app.time(movalue(1)) app.time(movalue(end))])
            set(app.UIAxesTS, 'ytick', app.offset ,'yticklabel',app.LiveChannels)
            ylim(app.UIAxesTS, [0 yTotal]);

            %change colour if already red
            idred = find(app.UITable.Data{mochannel,2} == 1);
            if ~isempty(idred)
                for i=idred
                    set(app.TSplot(i), 'Color', 'r');
                end
            end

            if ~isempty(app.markerSelect)
                app.display_markers;
            end


        end
        
        function initate_plots(app)

            %Initiate the channels display number
            [app.nchan, app.ntime] = size(app.ts);
            app.timeSample = 1:1:app.ntime;
            
            %Initiate the moving window
            app.create_windows_value('time')

            %Initiate Channel window
            app.chanSample = 1:1:app.nchan;
            app.create_windows_value('channels')

        end
        
        function update_table(app, chan)
            if nargin < 2
                chan = [];
            end
            if isempty(chan)
                tmpTab = table(app.channel, zeros(length(app.channel), 1), ...
                    'VariableNames', app.UITable.ColumnName);
                app.UITable.Data = tmpTab;
                app.UITable.ColumnEditable(1) = false;
                app.UITable.ColumnEditable(2) = true;
            else
                idx = find(strcmp(app.UITable.Data{:,1}, chan));
                oldValue = app.UITable.Data{idx, 2};
                if oldValue ==0
                    app.UITable.Data{idx, 2} = 1;
                else
                    app.UITable.Data{idx, 2} = 0;
                end
            end
            
        end
        
        
        function create_windows_value(app, type)
            switch type
                case 'time'
                    app.moving = app.WindowTime.Value*app.fs;
                    app.cellMoving = buffer(app.timeSample, app.moving, 1);
                    app.cellMovingIdx = 1;
                case 'channels'
                    app.cellChaning = buffer(app.chanSample, app.ChannelsDisplay.Value);
                    app.cellChaningIdx = 1;

            end
            
        end
        
        function display_markers(app)
            delete(app.markerplot);
            % Select the markers to display
            tmptab = app.markers(strcmp(app.markers.name, app.markerSelect), :);
            movalue = app.cellMoving(:, app.cellMovingIdx)';

            mark2disp = tmptab.onset_sample(ismember(tmptab.onset_sample,  movalue));
            mark2dispTime = app.time(mark2disp);
            nmark = size(mark2dispTime,2);

            hold(app.UIAxesTS, 'on');
            ylimTS = get(app.UIAxesTS, 'YLim');
            app.markerplot = plot(app.UIAxesTS, [mark2dispTime; mark2dispTime], repmat(ylimTS', 1, nmark), '-r');
            hold(app.UIAxesTS, 'off');

        end
        
        function initiateProcess(app, value)
            app.channel = app.ppb.process.outdata.(value).label;
            app.ts = app.ppb.process.outdata.(value).trial{1};
            app.fs = app.ppb.process.outdata.(value).fsample;
            app.time = app.ppb.process.outdata.(value).time{1};
            app.nSamples = length(app.time);
            app.markers = app.ppb.process.outdata.(value).markers;

            app.update_table;
            app.initate_plots;
            app.update_plot;

            % Initiate list of markers
            app.markerSelect = [];
            markersList = unique(app.markers.name);
            app.MarkerstodisplayDropDown.Items = markersList;
            set(app.MarkerstodisplayDropDown, 'Visible', 'on');
            set(app.MarkerstodisplayLabel, 'Visible', 'on');
            set(app.MarkerstodisplayDropDown, 'Enable', 'on');
            set(app.MarkerstodisplayLabel, 'Enable', 'on');
        end
    end
    
    methods (Access = public)

        function closeTheWindow(app)
            delete(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                app.ppb = varargin{1};
                app.done = 0;
                app.type = varargin{2};
                switch app.type 
                    case 'bad'
                        app.channel = app.ppb.seeg.hdr.label;
                        app.ts = app.ppb.seeg.data;
                        app.fs = app.ppb.seeg.hdr.Fs;
                        app.nSamples = app.ppb.seeg.hdr.nSamples;
                        app.time = linspace(0, (app.nSamples/app.fs),app.nSamples);
                        
                        app.update_table;
                        app.initate_plots;
                        app.update_plot;
                    case 'process'
                        % Initiate drop down   
                        process = fieldnames(app.ppb.process.outdata);
                        app.WhattovisualizeDropDown.Items = process;
                        app.WhattovisualizeDropDown.Visible = 'on';
                        app.WhattovisualizeLabel.Visible = 'on';
                        app.WhattovisualizeLabel.Enable = 'on';
                        app.WhattovisualizeDropDown.Enable = "on";

                        set(app.FilterHPButton, 'Visible', 'off');
                        set(app.FilterHPButton, 'Enable', 'off');
                        set(app.UITable, 'Visible', 'off');
                        % set(app.UITable, 'Enable', 'off');

                        % Iniate with the first item
                        app.initiateProcess(process{1});
                end
                
            else
                errordlg('ppb is missing.')
                delete(app)
                return
            end
            
        end

        % Button pushed function: ButtonForward
        function ButtonForwardPushed(app, event)
            app.cellMovingIdx = app.cellMovingIdx + 1;
            nIdx = size(app.cellMoving, 2);
            if app.cellMovingIdx > nIdx
                app.cellMovingIdx = nIdx;
            elseif app.cellMovingIdx < 1
                app.cellMovingIdx = 1;
            end
            app.update_plot;
        end

        % Button pushed function: ButtonBack
        function ButtonBackPushed(app, event)
            app.cellMovingIdx = app.cellMovingIdx - 1;
            nIdx = size(app.cellMoving, 2);
            if app.cellMovingIdx > nIdx
                app.cellMovingIdx = nIdx;
            elseif app.cellMovingIdx < 1
                app.cellMovingIdx = 1;
            end
            app.update_plot;
        end

        % Button pushed function: ButtonScrolldown
        function ButtonScrolldownPushed(app, event)
            app.cellChaningIdx = app.cellChaningIdx + 1;
            nIdx = size(app.cellChaning, 2);
            if app.cellChaningIdx > nIdx
                app.cellChaningIdx = nIdx;
            elseif app.cellChaningIdx < 1
                app.cellChaningIdx = 1;
            end
            app.update_plot;
        end

        % Button pushed function: ButtonScrollup
        function ButtonScrollupPushed(app, event)
            app.cellChaningIdx = app.cellChaningIdx - 1;
            nIdx = size(app.cellChaning, 2);
            if app.cellChaningIdx > nIdx
                app.cellChaningIdx = nIdx;
            elseif app.cellChaningIdx < 1
                app.cellChaningIdx = 1;
            end
            app.update_plot;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'uparrow'
                    yInterv = app.yL(2)- app.yL(1);
                    app.yL(1) = app.yL(1) + yInterv*0.05;
                    app.yL(2) = app.yL(2) - yInterv*0.05;
                    app.update_plot;
                case 'downarrow'
                    yInterv = app.yL(2)- app.yL(1);
                    app.yL(1) = app.yL(1) - yInterv*0.05;
                    app.yL(2) = app.yL(2) + yInterv*0.05;
                    app.update_plot;
            end
        end

        % Button down function: UIAxesTS
        function UIAxesTSButtonDown(app, event)
            %Find on which channel
            ch = event.IntersectionPoint(2);
            [~,closestIndex] = min(abs(app.offset-ch));
            onchan = app.cellChaning(:, app.cellChaningIdx);
            onchan(onchan == 0) = [];
            onchan = onchan(closestIndex);
            chaname = app.channel{onchan};
            app.update_table(chaname);

            % Change colour
            idx = find(strcmp(app.UITable.Data{:,1}, chaname));
            value = app.UITable.Data{idx, 2};
            if value ==1
                set(app.TSplot(closestIndex), 'color', 'r');
            else
                set(app.TSplot(closestIndex), 'color', "#0072BD");
            end
        end

        % Value changed function: WindowTime
        function WindowTimeValueChanged(app, event)
            value = app.WindowTime.Value;
            app.initate_plots;
            app.update_plot;
        end

        % Value changed function: ChannelsDisplay
        function ChannelsDisplayValueChanged(app, event)
            value = app.ChannelsDisplay.Value;
            app.initate_plots;
            app.update_plot;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmp(app.type, 'bad')
                answer = questdlg('Bad Channels won"t be saved if you quit. Do you want to quit anyway?', ...
                'Close window', 'Yes', 'No', 'Cancel');
                switch answer
                    case 'Yes'
                        app.done = 1;
                    case {'No','', ' '}
                        return
                end
            else
                app.done;
            end

        end

        % Button pushed function: FilterHPButton
        function FilterHPButtonPushed(app, event)
            progBar = waitbar(0.1, 'Filtering ...');
            filtered =  ft_preproc_highpassfilter(app.ts, app.fs, 0.5, [], 'firws');
            waitbar(0.9, progBar, 'Done.');
            app.oldts = app.ts;
            app.ts = filtered;
            app.update_plot;
            close(progBar)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if strcmp(app.type, 'bad')
                idred = find(app.UITable.Data{:, 2} == 1);
                app.ppb.preprocess.badChannels = app.UITable.Data{idred, 1};
                idez = find(app.UITable.Data{:, 2} == 2);
                app.ppb.preprocess.ez = app.UITable.Data{idez, 1};
            end
            app.done = 1;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            %app.UITable.Data(indices(1), indices(2)) = newData;
            app.update_plot;
        end

        % Value changed function: WhattovisualizeDropDown
        function WhattovisualizeDropDownValueChanged(app, event)
            value = app.WhattovisualizeDropDown.Value;
            app.initiateProcess(value);
        end

        % Value changed function: MarkerstodisplayDropDown
        function MarkerstodisplayDropDownValueChanged(app, event)
            value = app.MarkerstodisplayDropDown.Value;
            app.markerSelect = value;

            app.display_markers;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxesTS = uiaxes(app.UIFigure);
            app.UIAxesTS.ButtonDownFcn = createCallbackFcn(app, @UIAxesTSButtonDown, true);
            app.ButtonForward = uibutton(app.UIFigure, 'push');
            app.ButtonForward.ButtonPushedFcn = createCallbackFcn(app, @ButtonForwardPushed, true);
            app.ButtonForward.Tag = 'forward';
            app.ButtonForward.Text = '>>';
            app.ButtonBack = uibutton(app.UIFigure, 'push');
            app.ButtonBack.ButtonPushedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.ButtonBack.Tag = 'Back';
            app.ButtonBack.Text = '<<';
            app.ButtonScrolldown = uibutton(app.UIFigure, 'push');
            app.ButtonScrolldown.ButtonPushedFcn = createCallbackFcn(app, @ButtonScrolldownPushed, true);
            app.ButtonScrolldown.Tag = 'scrolldown';
            app.ButtonScrolldown.Text = 'v';
            app.ButtonScrollup = uibutton(app.UIFigure, 'push');
            app.ButtonScrollup.ButtonPushedFcn = createCallbackFcn(app, @ButtonScrollupPushed, true);
            app.ButtonScrollup.Tag = 'scrollup';
            app.ButtonScrollup.Text = '^';
            app.NumberofChannelsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofChannelsEditFieldLabel.Text = 'Number of Channels';
            app.ChannelsDisplay = uieditfield(app.UIFigure, 'numeric');
            app.ChannelsDisplay.ValueChangedFcn = createCallbackFcn(app, @ChannelsDisplayValueChanged, true);
            app.ChannelsDisplay.Value = 10;
            app.WinodwTimeEditFieldLabel = uilabel(app.UIFigure);
            app.WinodwTimeEditFieldLabel.Text = 'Winodw Time';
            app.WindowTime = uieditfield(app.UIFigure, 'numeric');
            app.WindowTime.ValueChangedFcn = createCallbackFcn(app, @WindowTimeValueChanged, true);
            app.WindowTime.Value = 10;
            app.FilterHPButton = uibutton(app.UIFigure, 'push');
            app.FilterHPButton.ButtonPushedFcn = createCallbackFcn(app, @FilterHPButtonPushed, true);
            app.FilterHPButton.Text = 'Filter HP';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Channels'; 'Bad'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.WhattovisualizeDropDown = uidropdown(app.UIFigure);
            app.WhattovisualizeDropDown.Items = {};
            app.WhattovisualizeDropDown.ValueChangedFcn = createCallbackFcn(app, @WhattovisualizeDropDownValueChanged, true);
            app.WhattovisualizeDropDown.Enable = 'off';
            app.WhattovisualizeDropDown.Visible = 'off';
            app.WhattovisualizeDropDown.Value = {};
            app.MarkerstodisplayDropDown = uidropdown(app.UIFigure);
            app.MarkerstodisplayDropDown.Items = {};
            app.MarkerstodisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerstodisplayDropDownValueChanged, true);
            app.MarkerstodisplayDropDown.Enable = 'off';
            app.MarkerstodisplayDropDown.Visible = 'off';
            app.MarkerstodisplayDropDown.Value = {};
            app.WhattovisualizeLabel = uilabel(app.UIFigure);
            app.WhattovisualizeLabel.Enable = 'off';
            app.WhattovisualizeLabel.Visible = 'off';
            app.WhattovisualizeLabel.Text = 'What to visualize?';
            app.MarkerstodisplayLabel = uilabel(app.UIFigure);
            app.MarkerstodisplayLabel.Enable = 'off';
            app.MarkerstodisplayLabel.Visible = 'off';
            app.MarkerstodisplayLabel.Text = 'Markers to display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectBadChannels(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end