classdef visualize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SelectionPanel        matlab.ui.container.Panel
        TrialsLabel           matlab.ui.control.Label
        TrialsDropDown        matlab.ui.control.DropDown
        TFselectLabel         matlab.ui.control.Label
        TFselectDropDown      matlab.ui.control.DropDown
        ShowButton            matlab.ui.control.Button
        ChannelsListBox       matlab.ui.control.ListBox
        ChannelsListBoxLabel  matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        done % Description
    end
    
    properties (Access = private)
        data % Description
        type
        Ax1plot
        Ax1marker
        Ax2plot
        Ax2marker
        colorbar
        file
        nodesName1
        arrows1
        nodesName2
        arrows2
    end
    
    methods (Access = public)
        
        function closeWindow(app)
            delete(app)            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataIn, type)
            if isstring(dataIn) || ischar(dataIn)
                tmp = load(dataIn);
                app.data = tmp.data;
                app.file = 1;
            else
                app.data = dataIn;
                app.file = 0;
            end
            app.ChannelsListBox.Items = app.data.label;
            app.done = 0;
            app.type = type;

            if strcmp(type, 'TF')
                TFtype = [];
                if isfield(app.data, 'trialPSD')
                    TFtype = [TFtype; "PSD"];
                end
                if isfield(app.data, 'TFmap')
                    TFtype = [TFtype; "TFmap"];
                end
                app.TFselectDropDown.Items = TFtype;
                set(app.TFselectDropDown, 'Visible', 'on');
                set(app.TFselectDropDown, 'Enable', 'on');
                set(app.TFselectLabel, 'Visible', 'on');
                set(app.TFselectLabel, 'Enable', 'on');

            elseif strcmp(type, 'connectivity')
                nbrTrials = arrayfun(@(x) strcat('Trial - ', num2str(x)), 1:length(app.data.trial), 'un', 0);
                app.TrialsDropDown.Items = nbrTrials;

                set(app.ChannelsListBox, 'Multiselect', 'on')
                set(app.TrialsDropDown, 'Visible', 'on');
                set(app.TrialsDropDown, 'Enable', 'on');
                set(app.TrialsLabel, 'Visible', 'on');
                set(app.TrialsLabel, 'Enable', 'on');
                app.type = 'connStart';
                app.ShowButtonPushed([]);
                app.type = 'connectivity';
            end
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            chan = app.ChannelsListBox.Value;
            if ~isempty(chan)
                if ischar(chan)
                    idx = strcmp(app.data.label, chan);
                else
                    idx = cellfun(@(x) find(strcmp(app.data.label, x)), chan);
                end
            end
            switch app.type
                case 'epochs'
                    nsample = length(app.data.time);
                    ntrials = size(app.data.trial, 2);
                    toPlot = zeros(ntrials, nsample);
                    for n=1:ntrials
                        toPlot(n, :) = app.data.trial{n}(idx, :);
                    end
        
                    % Plot the trials
                    delete(app.Ax1plot);
                    delete(app.Ax1marker);
                    hold(app.UIAxes, 'on');
                    app.Ax1plot = plot(app.UIAxes, app.data.time, toPlot);
                    app.Ax1marker = plot(app.UIAxes, [0.0 0.0], [min(toPlot(:)) max(toPlot(:))], '-r', ...
                        'LineWidth', 1);
                    hold(app.UIAxes, 'off');
                    title(app.UIAxes, chan)
                    xlabel(app.UIAxes, 'Time (s)')
                    ylabel(app.UIAxes, 'Amplitude')
        
                    % Plot average
                    delete(app.Ax2plot);
                    delete(app.Ax2marker);
                    hold(app.UIAxes2, 'on');
                    app.Ax2plot = plot(app.UIAxes2, app.data.time, app.data.avg(idx, :), '-b');
                    app.Ax2marker = plot(app.UIAxes2, [0 0], [min(app.data.avg(idx, :)) max(app.data.avg(idx, :))], ...
                        '-r', 'LineWidth', 1);
                    hold(app.UIAxes2, 'off');
                    title(app.UIAxes2, strcat(chan, ' Average'))
                    xlabel(app.UIAxes2, 'Time (s)')
                    ylabel(app.UIAxes2, 'Amplitude')
                case 'TF'
                    tftype = app.TFselectDropDown.Value;
                    switch tftype
                        case "PSD"
                            ntrials = size(app.data.trialPSD, 2);
                            freqs = app.data.fPSD{1};
                            nfreq = length(freqs);
                            toPlot = zeros(ntrials, nfreq);
                            for n=1:ntrials
                                toPlot(n, :) = 10*log10(app.data.trialPSD{n}(idx, :));
                            end

                             % Plot the trials
                            delete(app.Ax1plot);
                            delete(app.colorbar)
                            reset(app.UIAxes);
                            app.Ax1plot = plot(app.UIAxes, freqs, toPlot);
                            title(app.UIAxes, chan)
                            xlabel(app.UIAxes, 'Frequency (Hz)')
                            ylabel(app.UIAxes, 'PSD (dB/Hz)')
                            
                            % Plot average
                            delete(app.Ax2plot);
                            app.Ax2plot = plot(app.UIAxes2, freqs, 10*log10(app.data.avgPSD(idx, 1:nfreq)), '-b');
                            title(app.UIAxes2, strcat(chan, ' Average'))
                            xlabel(app.UIAxes2, 'Frequency (Hz)')
                            ylabel(app.UIAxes2, 'PSD (dB/Hz)')

                        case "TFmap"
                            % Image the TF map
                            delete(app.Ax1plot);
                            TFplot = squeeze(app.data.TFmap.powspctrm(idx, :, :));
                            app.Ax1plot = imagesc(app.UIAxes, TFplot);
                            set(app.UIAxes, 'YDir', 'normal');
                            nx = length(app.data.TFmap.xtime);
                            sep = round(nx/10);
                            xt = 1:sep:nx;
                            set(app.UIAxes,'YTick', 1:length(app.data.TFmap.freq),'YTickLabel', round(app.data.TFmap.freq), ...
                                'XTick', xt, 'XTickLabel', app.data.TFmap.xtime(xt));
                            app.colorbar = colorbar(app.UIAxes);
                            title(app.UIAxes, chan)
                            xlabel(app.UIAxes, 'Time (s)')
                            ylabel(app.UIAxes, 'Frequency (Hz)')
                            app.UIAxes.XLim = [0 xt(end)];
                            app.UIAxes.YLim = [1 length(app.data.TFmap.freq)];

                    end
                case 'connectivity'
                    % Check the channels number
                    if length(chan) < 2
                        msgbox('You must select multiple channels!!');
                        return
                    end
                    % get the trial to show
                    trialType = app.TrialsDropDown.Value;
                    trial = extract(trialType, digitsPattern);
                    trial = str2double(trial{1});

                     % Plot the trials
                    delete(app.Ax1plot);
                    delete(app.colorbar)
                    reset(app.UIAxes);
                    [r, app.nodesName1, app.arrows1, app.Ax1plot] = plot_connectivity(app.UIAxes, idx, app.data.label, app.data.trial{trial});
                    l = r+5;
                    app.UIAxes.XLim = [-l l];
                    app.UIAxes.YLim = [-l l];
                    title(app.UIAxes, trialType);

                     % Plot average
                    delete(app.Ax2plot);
                    reset(app.UIAxes2);
                    [r, app.nodesName2, app.arrows2, app.Ax2plot] = plot_connectivity(app.UIAxes2, idx, app.data.label, app.data.avg);
                    l = r+5;
                    app.UIAxes2.XLim = [-l l];
                    app.UIAxes2.YLim = [-l l];
                    title(app.UIAxes2, 'Average Trials');

                case 'connStart'
                    % Plot the matrix connectivity
                    delete(app.Ax1plot);
                    delete(app.colorbar)
                    reset(app.UIAxes);
                    app.Ax1plot = imagesc(app.UIAxes, app.data.avg);
                    nbrChan = length(app.data.label);
                    set(app.UIAxes, 'XTick', 1:nbrChan, 'XTickLabel', ...
                        app.data.label, 'XTickLabelRotation', 45, ...
                    'YTick', 1:nbrChan, 'YTickLabel', app.data.label)
                    app.colorbar = colorbar(app.UIAxes);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.done = 1;
            if app.file
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SelectionPanel = uipanel(app.UIFigure);
            app.SelectionPanel.Title = 'Selection';
            app.ChannelsListBoxLabel = uilabel(app.SelectionPanel);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.SelectionPanel);
            app.ChannelsListBox.Items = {};
            app.ChannelsListBox.Value = {};
            app.ShowButton = uibutton(app.SelectionPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.TFselectDropDown = uidropdown(app.SelectionPanel);
            app.TFselectDropDown.Items = {};
            app.TFselectDropDown.Enable = 'off';
            app.TFselectDropDown.Visible = 'off';
            app.TFselectDropDown.Value = {};
            app.TFselectLabel = uilabel(app.SelectionPanel);
            app.TFselectLabel.Enable = 'off';
            app.TFselectLabel.Visible = 'off';
            app.TFselectLabel.Text = 'TF select';
            app.TrialsDropDown = uidropdown(app.SelectionPanel);
            app.TrialsDropDown.Items = {};
            app.TrialsDropDown.Enable = 'off';
            app.TrialsDropDown.Visible = 'off';
            app.TrialsDropDown.Value = {};
            app.TrialsLabel = uilabel(app.SelectionPanel);
            app.TrialsLabel.Enable = 'off';
            app.TrialsLabel.Visible = 'off';
            app.TrialsLabel.Text = 'Trials';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end